classdef DiabetesGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        HomeTab                   matlab.ui.container.Tab
        Image14                   matlab.ui.control.Image
        PleaseSelectwhatyouwanttoexamineyourpatientforLabel  matlab.ui.control.Label
        LumpyskinButton           matlab.ui.control.Button
        HeartcomplicationsButton  matlab.ui.control.Button
        DiabetesButton            matlab.ui.control.Button
        GeneralDiseasesButton     matlab.ui.control.Button
        GeneralDiseasesTab        matlab.ui.container.Tab
        DiabetesTab               matlab.ui.container.Tab
        Image13                   matlab.ui.control.Image
        PleaseenterthepatientsvaluesbellowLabel  matlab.ui.control.Label
        PressbuttonforthepredictionLabel  matlab.ui.control.Label
        MoreinformationaboutdiabetesLabel  matlab.ui.control.Label
        Graphicdiabetestyp1Label  matlab.ui.control.Label
        Image12                   matlab.ui.control.Image
        Image10                   matlab.ui.control.Image
        Image9                    matlab.ui.control.Image
        PregnanciesSlider         matlab.ui.control.Slider
        PregnanciesSliderLabel    matlab.ui.control.Label
        Image8                    matlab.ui.control.Image
        Image7                    matlab.ui.control.Image
        Image6                    matlab.ui.control.Image
        Image5                    matlab.ui.control.Image
        Image4                    matlab.ui.control.Image
        Image3                    matlab.ui.control.Image
        Image2                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        DropDown                  matlab.ui.control.DropDown
        Hyperlink                 matlab.ui.control.Hyperlink
        EditField2                matlab.ui.control.EditField
        DiagnosisButton           matlab.ui.control.Button
        AgeSlider                 matlab.ui.control.Slider
        AgeSliderLabel            matlab.ui.control.Label
        BMISlider                 matlab.ui.control.Slider
        BMISliderLabel            matlab.ui.control.Label
        InsulinSlider             matlab.ui.control.Slider
        InsulinSliderLabel        matlab.ui.control.Label
        SkinThicknessSlider       matlab.ui.control.Slider
        SkinThicknessSliderLabel  matlab.ui.control.Label
        BloodPressureSlider       matlab.ui.control.Slider
        BloodPressureSliderLabel  matlab.ui.control.Label
        GlucoseSlider             matlab.ui.control.Slider
        GlucoseSliderLabel        matlab.ui.control.Label
        HeartcomplicationsTab     matlab.ui.container.Tab
        LumpyskinTab              matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneralDiseasesButton
        function GeneralDiseasesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.GeneralDiseasesTab; % Open correlating Tab
        end

        % Button pushed function: DiabetesButton
        function DiabetesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DiabetesTab; % Open correlating Tab
        end

        % Button pushed function: HeartcomplicationsButton
        function HeartcomplicationsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HeartcomplicationsTab; % Open correlating Tab
        end

        % Button pushed function: LumpyskinButton
        function LumpyskinButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.LumpyskinTab; % Open correlating Tab
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.GeneralDiseasesButton = uibutton(app.HomeTab, 'push');
            app.GeneralDiseasesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralDiseasesButtonPushed, true);
            app.GeneralDiseasesButton.Text = 'GeneralDiseases';
            app.DiabetesButton = uibutton(app.HomeTab, 'push');
            app.DiabetesButton.ButtonPushedFcn = createCallbackFcn(app, @DiabetesButtonPushed, true);
            app.DiabetesButton.Text = 'Diabetes';
            app.HeartcomplicationsButton = uibutton(app.HomeTab, 'push');
            app.HeartcomplicationsButton.ButtonPushedFcn = createCallbackFcn(app, @HeartcomplicationsButtonPushed, true);
            app.HeartcomplicationsButton.Text = 'Heartcomplications';
            app.LumpyskinButton = uibutton(app.HomeTab, 'push');
            app.LumpyskinButton.ButtonPushedFcn = createCallbackFcn(app, @LumpyskinButtonPushed, true);
            app.LumpyskinButton.Text = 'Lumpyskin';
            app.PleaseSelectwhatyouwanttoexamineyourpatientforLabel = uilabel(app.HomeTab);
            app.PleaseSelectwhatyouwanttoexamineyourpatientforLabel.Text = 'Please Select what you want to examine your patient for:';
            app.Image14 = uiimage(app.HomeTab);
            app.Image14.ImageSource = fullfile(pathToMLAPP, 'Teamprojekt Logo.jpg');
            app.GeneralDiseasesTab = uitab(app.TabGroup);
            app.GeneralDiseasesTab.Title = 'GeneralDiseases';
            app.DiabetesTab = uitab(app.TabGroup);
            app.DiabetesTab.Title = 'Diabetes';
            app.GlucoseSliderLabel = uilabel(app.DiabetesTab);
            app.GlucoseSliderLabel.Text = 'Glucose';
            app.GlucoseSlider = uislider(app.DiabetesTab);
            app.GlucoseSlider.Limits = [0 200];
            app.GlucoseSlider.MajorTicks = [0 50 100 150 200];
            app.GlucoseSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200];
            app.BloodPressureSliderLabel = uilabel(app.DiabetesTab);
            app.BloodPressureSliderLabel.Text = 'BloodPressure';
            app.BloodPressureSlider = uislider(app.DiabetesTab);
            app.BloodPressureSlider.Limits = [0 200];
            app.BloodPressureSlider.MajorTicks = [0 50 100 150 200];
            app.BloodPressureSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200];
            app.SkinThicknessSliderLabel = uilabel(app.DiabetesTab);
            app.SkinThicknessSliderLabel.Text = 'SkinThickness';
            app.SkinThicknessSlider = uislider(app.DiabetesTab);
            app.SkinThicknessSlider.Limits = [0 80];
            app.SkinThicknessSlider.MajorTicks = [0 20 40 60 80];
            app.SkinThicknessSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.InsulinSliderLabel = uilabel(app.DiabetesTab);
            app.InsulinSliderLabel.Text = 'Insulin';
            app.InsulinSlider = uislider(app.DiabetesTab);
            app.InsulinSlider.Limits = [0 500];
            app.InsulinSlider.MinorTicks = [0 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425 450 475 500];
            app.BMISliderLabel = uilabel(app.DiabetesTab);
            app.BMISliderLabel.Text = 'BMI';
            app.BMISlider = uislider(app.DiabetesTab);
            app.BMISlider.Limits = [0 50];
            app.BMISlider.MajorTicks = [0 10 20 30 40 50];
            app.BMISlider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50];
            app.AgeSliderLabel = uilabel(app.DiabetesTab);
            app.AgeSliderLabel.Text = 'Age';
            app.AgeSlider = uislider(app.DiabetesTab);
            app.AgeSlider.MajorTicks = [0 20 40 60 80 100];
            app.AgeSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.DiagnosisButton = uibutton(app.DiabetesTab, 'push');
            app.DiagnosisButton.Text = 'Diagnosis';
            app.EditField2 = uieditfield(app.DiabetesTab, 'text');
            app.EditField2.Value = '       For more information, chlick here >>> ';
            app.Hyperlink = uihyperlink(app.DiabetesTab);
            app.Hyperlink.Text = 'https://www.ddg.info';
            app.DropDown = uidropdown(app.DiabetesTab);
            app.DropDown.Items = {'Definition ', 'Typ 1 vs. Typ 2', 'Symptoms', 'Treatments'};
            app.DropDown.ItemsData = {'Hallo'};
            app.DropDown.Value = 'Hallo';
            app.Image = uiimage(app.DiabetesTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMG_8032.jpg');
            app.Image2 = uiimage(app.DiabetesTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'BloodPressure.jpg');
            app.Image3 = uiimage(app.DiabetesTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'IMG_8036.jpg');
            app.Image4 = uiimage(app.DiabetesTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'IMG_8041.jpg');
            app.Image5 = uiimage(app.DiabetesTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'IMG_8043.jpg');
            app.Image6 = uiimage(app.DiabetesTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'IMG_8044.jpg');
            app.Image7 = uiimage(app.DiabetesTab);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'IMG_8046.jpg');
            app.Image8 = uiimage(app.DiabetesTab);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'IMG_8040.jpg');
            app.PregnanciesSliderLabel = uilabel(app.DiabetesTab);
            app.PregnanciesSliderLabel.Text = 'Pregnancies';
            app.PregnanciesSlider = uislider(app.DiabetesTab);
            app.PregnanciesSlider.Limits = [0 20];
            app.PregnanciesSlider.MajorTicks = [0 5 10 15 20];
            app.PregnanciesSlider.MajorTickLabels = {'0', '5', '10', '15', '20'};
            app.PregnanciesSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.Image9 = uiimage(app.DiabetesTab);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'IMG_8048.jpg');
            app.Image10 = uiimage(app.DiabetesTab);
            app.Image10.ImageSource = fullfile(pathToMLAPP, 'IMG_8047.jpg');
            app.Image12 = uiimage(app.DiabetesTab);
            app.Image12.ImageSource = fullfile(pathToMLAPP, 'IMG_8045.jpg');
            app.Graphicdiabetestyp1Label = uilabel(app.DiabetesTab);
            app.Graphicdiabetestyp1Label.Text = 'Graphic diabetes typ 1';
            app.MoreinformationaboutdiabetesLabel = uilabel(app.DiabetesTab);
            app.MoreinformationaboutdiabetesLabel.Text = 'More information about diabetes:';
            app.PressbuttonforthepredictionLabel = uilabel(app.DiabetesTab);
            app.PressbuttonforthepredictionLabel.Text = 'Press button for the prediction:';
            app.PleaseenterthepatientsvaluesbellowLabel = uilabel(app.DiabetesTab);
            app.PleaseenterthepatientsvaluesbellowLabel.Text = '► Please enter the patients values bellow:';
            app.Image13 = uiimage(app.DiabetesTab);
            app.Image13.ImageSource = fullfile(pathToMLAPP, 'DiabetesSymbol.jpeg');
            app.HeartcomplicationsTab = uitab(app.TabGroup);
            app.HeartcomplicationsTab.Title = 'Heartcomplications';
            app.LumpyskinTab = uitab(app.TabGroup);
            app.LumpyskinTab.Title = 'Lumpyskin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiabetesGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end