classdef Menu_fixed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        Image23                         matlab.ui.control.Image
        NamesLabel                      matlab.ui.control.Label
        ImageOne                        matlab.ui.control.Image
        ImageTwo                        matlab.ui.control.Image
        ImageThree                      matlab.ui.control.Image
        ToDo3Label                      matlab.ui.control.Label
        Step3Label                      matlab.ui.control.Label
        ToDo2Label                      matlab.ui.control.Label
        Step2Label                      matlab.ui.control.Label
        ToDo1Label                      matlab.ui.control.Label
        Step1Label                      matlab.ui.control.Label
        WelcomeLabel                    matlab.ui.control.Label
        SelectHomeLabel                 matlab.ui.control.Label
        LumpyskinButton                 matlab.ui.control.Button
        HeartcomplicationsButton        matlab.ui.control.Button
        DiabetesButton                  matlab.ui.control.Button
        GeneralDiseasesButton           matlab.ui.control.Button
        GeneralDiseasesTab              matlab.ui.container.Tab
        DeleteSymptomLabel              matlab.ui.control.Label
        YourselectedsymptomsListBox     matlab.ui.control.ListBox
        YourselectedsymptomsListBoxLabel  matlab.ui.control.Label
        VentilaterLabel                 matlab.ui.control.Label
        CoughLabel                      matlab.ui.control.Label
        WashLabel                       matlab.ui.control.Label
        CleanLabel                      matlab.ui.control.Label
        WashhandsLabel                  matlab.ui.control.Label
        ImageWashHands                  matlab.ui.control.Image
        ImageWindow                     matlab.ui.control.Image
        ImageCough                      matlab.ui.control.Image
        ImageWashing                    matlab.ui.control.Image
        Imageclean                      matlab.ui.control.Image
        HomeButtonGeneral               matlab.ui.control.Button
        SelectSymptomLabel              matlab.ui.control.Label
        SelectionLabel2                 matlab.ui.control.Label
        Hyperlink_General               matlab.ui.control.Hyperlink
        SearchEditField                 matlab.ui.control.EditField
        SearchforsymptomEditFieldLabel  matlab.ui.control.Label
        Prediction                      matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        InfoPredictionLabel             matlab.ui.control.Label
        MoreInformationLabel            matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        InfoButton_General              matlab.ui.control.StateButton
        EditField                       matlab.ui.control.EditField
        GeneralDiseasesTextArea         matlab.ui.control.TextArea
        DIAGNOSISButton_General         matlab.ui.control.Button
        DiabetesTab                     matlab.ui.container.Tab
        TreatmentLabel3                 matlab.ui.control.Label
        TreatmentLabel2                 matlab.ui.control.Label
        TreatmentLabel1                 matlab.ui.control.Label
        PreventionLabel3                matlab.ui.control.Label
        PreventionLabel2                matlab.ui.control.Label
        PreventionLabel1                matlab.ui.control.Label
        Treatment3                      matlab.ui.control.Image
        Treatment2                      matlab.ui.control.Image
        Treatment1                      matlab.ui.control.Image
        TreatmentLabel                  matlab.ui.control.Label
        Sports                          matlab.ui.control.Image
        ImagehealthyWeight              matlab.ui.control.Image
        ImageFood                       matlab.ui.control.Image
        PreventionLabel                 matlab.ui.control.Label
        PredictionDiabetes              matlab.ui.control.Label
        HomeButtonDiabetes              matlab.ui.control.Button
        HoverCursorLabelDiabetes        matlab.ui.control.Label
        SelectionLabel3                 matlab.ui.control.Label
        DIAGNOSISButton_Diabetes        matlab.ui.control.Button
        ImagePregnancies                matlab.ui.control.Image
        Hyperlink2                      matlab.ui.control.Hyperlink
        EditField_5                     matlab.ui.control.EditField
        DPFSlider                       matlab.ui.control.Slider
        DPFSliderLabel                  matlab.ui.control.Label
        PregnanciesSlider               matlab.ui.control.Slider
        PregnanciesSliderLabel          matlab.ui.control.Label
        AgeSlider                       matlab.ui.control.Slider
        AgeSliderLabel                  matlab.ui.control.Label
        BMISlider                       matlab.ui.control.Slider
        BMISliderLabel                  matlab.ui.control.Label
        InsulinSlider                   matlab.ui.control.Slider
        InsulinSliderLabel              matlab.ui.control.Label
        SkinThicknessSlider             matlab.ui.control.Slider
        SkinThicknessSliderLabel        matlab.ui.control.Label
        BloodPressureSlider             matlab.ui.control.Slider
        BloodPressureSliderLabel        matlab.ui.control.Label
        GlucoseSlider                   matlab.ui.control.Slider
        GlucoseSliderLabel              matlab.ui.control.Label
        EditFieldAge                    matlab.ui.control.NumericEditField
        EditFieldDPF                    matlab.ui.control.NumericEditField
        EditFieldBMI                    matlab.ui.control.NumericEditField
        EditFieldInsulin                matlab.ui.control.NumericEditField
        EditFieldSkin                   matlab.ui.control.NumericEditField
        EditFieldBP                     matlab.ui.control.NumericEditField
        EditFieldGluc                   matlab.ui.control.NumericEditField
        EditFieldPreg                   matlab.ui.control.NumericEditField
        showInfo                        matlab.ui.control.Label
        infoAge                         matlab.ui.control.Label
        InfoDPF                         matlab.ui.control.Label
        InfoBMI                         matlab.ui.control.Label
        InfoInsulin                     matlab.ui.control.Label
        InfoSkinThickness               matlab.ui.control.Label
        InfoBloodPressure               matlab.ui.control.Label
        InfoGlucose                     matlab.ui.control.Label
        InfoPregnancies                 matlab.ui.control.Label
        DiabetesTextArea                matlab.ui.control.TextArea
        InfoButton_Diabetes             matlab.ui.control.StateButton
        ImageDPF                        matlab.ui.control.Image
        InfoPredictionLabel_4           matlab.ui.control.Label
        ImageBMI                        matlab.ui.control.Image
        ImageInsulin                    matlab.ui.control.Image
        ImageAge                        matlab.ui.control.Image
        ImageSkinThickness              matlab.ui.control.Image
        ImageGlucose                    matlab.ui.control.Image
        ImageBloodPressure              matlab.ui.control.Image
        HeartcomplicationsTab           matlab.ui.container.Tab
        MaleLabel                       matlab.ui.control.Label
        vsLabel                         matlab.ui.control.Label
        FemaleLabel                     matlab.ui.control.Label
        HeartAttackSymptomsLabel        matlab.ui.control.Label
        NauseaVomitLabel                matlab.ui.control.Label
        HeartburnIndigestionLabel       matlab.ui.control.Label
        FlulikeSymptomsLabel            matlab.ui.control.Label
        InsomniaLabel                   matlab.ui.control.Label
        FatigueLabel                    matlab.ui.control.Label
        ColdSweatingLabel               matlab.ui.control.Label
        ChestDiscomfortLabel            matlab.ui.control.Label
        UpperBodyPainLabel              matlab.ui.control.Label
        ShortnessofBreathLabel          matlab.ui.control.Label
        LightheadednessLabel            matlab.ui.control.Label
        HeartSymptomsImage              matlab.ui.control.Image
        HomeButtonHeart                 matlab.ui.control.Button
        showInfo_2                      matlab.ui.control.Label
        Heart1TextArea                  matlab.ui.control.TextArea
        InfoHTextAreaLabel              matlab.ui.control.Label
        InfoButton_Heart                matlab.ui.control.StateButton
        QuestionLabel                   matlab.ui.control.TextArea
        GeneralButton                   matlab.ui.control.Button
        ExactButton                     matlab.ui.control.Button
        ExactMeasurementsTab            matlab.ui.container.Tab
        TypesLabel                      matlab.ui.control.Label
        HeartLabelPic1                  matlab.ui.control.Label
        HeartLabelPic2                  matlab.ui.control.Label
        HeartLabelPic3                  matlab.ui.control.Label
        HeartLabelPic4                  matlab.ui.control.Label
        HeartLabelPic5                  matlab.ui.control.Label
        ImageHeart1                     matlab.ui.control.Image
        ImageHeart2                     matlab.ui.control.Image
        ImageHeart3                     matlab.ui.control.Image
        ImageHeart4                     matlab.ui.control.Image
        ImageHeart5                     matlab.ui.control.Image
        ImageHeartPatient               matlab.ui.control.Image
        BackButtonHeartEx               matlab.ui.control.Button
        HeartExPredictionLabel          matlab.ui.control.Label
        HomeButtonHeartEx               matlab.ui.control.Button
        HoverCursorLabel                matlab.ui.control.Label
        EnterValuesLabel                matlab.ui.control.Label
        PressButtonLabel                matlab.ui.control.Label
        HeartExHyperlink                matlab.ui.control.Hyperlink
        showInfo_3                      matlab.ui.control.Label
        HeartExFurtherInformation       matlab.ui.control.EditField
        slopeDropDown                   matlab.ui.control.DropDown
        slopeDropDownLabel              matlab.ui.control.Label
        ThalDropDown                    matlab.ui.control.DropDown
        ThalDropDownLabel               matlab.ui.control.Label
        Heart2TextArea                  matlab.ui.control.TextArea
        InfoTextAreaLabel               matlab.ui.control.Label
        OldpeakEditField                matlab.ui.control.NumericEditField
        OldpeakEditFieldLabel           matlab.ui.control.Label
        restecgDropDown                 matlab.ui.control.DropDown
        restecgDropDownLabel            matlab.ui.control.Label
        cpDropDown                      matlab.ui.control.DropDown
        cpDropDownLabel                 matlab.ui.control.Label
        HeartExSexDropDown              matlab.ui.control.DropDown
        SexDropDown_2Label              matlab.ui.control.Label
        caSpinner                       matlab.ui.control.Spinner
        caSpinnerLabel                  matlab.ui.control.Label
        thalchEditField                 matlab.ui.control.NumericEditField
        thalchEditFieldLabel            matlab.ui.control.Label
        CholEditField                   matlab.ui.control.NumericEditField
        CholEditFieldLabel              matlab.ui.control.Label
        TrestbpsEditField               matlab.ui.control.NumericEditField
        TrestbpsEditFieldLabel          matlab.ui.control.Label
        HeartExAgeEditField             matlab.ui.control.NumericEditField
        AgeEditField_2Label             matlab.ui.control.Label
        InfoButton_HeartEx              matlab.ui.control.StateButton
        DIAGNOSISButton_HeartEx         matlab.ui.control.Button
        ExangButtonGroup                matlab.ui.container.ButtonGroup
        ExangFalseButton                matlab.ui.control.RadioButton
        ExangLabel                      matlab.ui.control.Label
        ExangTrueButton                 matlab.ui.control.RadioButton
        ExangnoentryButton              matlab.ui.control.RadioButton
        FBSButtonGroup                  matlab.ui.container.ButtonGroup
        FBSFalseButton                  matlab.ui.control.RadioButton
        FBSLabel                        matlab.ui.control.Label
        FBSTrueButton                   matlab.ui.control.RadioButton
        FBSnoentryButton                matlab.ui.control.RadioButton
        GeneralMeasurementsTab          matlab.ui.container.Tab
        BackButtonHeartGen              matlab.ui.control.Button
        ImageHealth                     matlab.ui.control.Image
        ImageGrade                      matlab.ui.control.Image
        ImageDiabetes                   matlab.ui.control.Image
        ImageSex                        matlab.ui.control.Image
        HeartGenTextArea                matlab.ui.control.TextArea
        InfoTextAreaLabel_2             matlab.ui.control.Label
        InfoButton_HeartGen             matlab.ui.control.StateButton
        HomeButtonHeartGen              matlab.ui.control.Button
        SelectionLabel4                 matlab.ui.control.Label
        Prediction_3                    matlab.ui.control.Label
        Hyperlink4                      matlab.ui.control.Hyperlink
        InfoPredictionLabel_5           matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        HealthcarecoverageCheckBox      matlab.ui.control.CheckBox
        DoctorssvisitiCostsCheckBox     matlab.ui.control.CheckBox
        PhysicalhealthEditField         matlab.ui.control.NumericEditField
        Dayswithbadphysicalhealthinthelast30daysEditFieldLabel  matlab.ui.control.Label
        MentalhealthEditField           matlab.ui.control.NumericEditField
        Dayswithbadmentalhealthinthelast30daysEditFieldLabel  matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        IncomeEditField                 matlab.ui.control.NumericEditField
        AnnualhouseholdincomeallsourcesLabel  matlab.ui.control.Label
        GradeDropDown                   matlab.ui.control.DropDown
        HighestcompletedGradeDropDownLabel  matlab.ui.control.Label
        GeneralHealthDropDown           matlab.ui.control.DropDown
        GeneralHealthDropDownLabel      matlab.ui.control.Label
        DiabetesDropDown                matlab.ui.control.DropDown
        DiabetesDropDownLabel           matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        BMIEditField                    matlab.ui.control.NumericEditField
        BMIroundedtointegerEditFieldLabel  matlab.ui.control.Label
        DIAGNOSISButton_HeartGen        matlab.ui.control.Button
        DifficultyWalkingCheckBox       matlab.ui.control.CheckBox
        FruitCheckBox                   matlab.ui.control.CheckBox
        VegetableCheckBox               matlab.ui.control.CheckBox
        BloodcholersterolCheckBox       matlab.ui.control.CheckBox
        ExercisedCheckBox               matlab.ui.control.CheckBox
        StrokeCheckBox                  matlab.ui.control.CheckBox
        HighbloodpressureCheckBox       matlab.ui.control.CheckBox
        BloodcholesterolCheckupCheckBox  matlab.ui.control.CheckBox
        SmokingCheckBox                 matlab.ui.control.CheckBox
        AlcoholconsumptionCheckBox      matlab.ui.control.CheckBox
        LumpyskinTab                    matlab.ui.container.Tab
        HomeButtonLumpySkin             matlab.ui.control.Button
        InfoLabel_2                     matlab.ui.control.Label
        SelectionLabel                  matlab.ui.control.Label
        tmxEditField                    matlab.ui.control.NumericEditField
        tmxEditFieldLabel               matlab.ui.control.Label
        tmpEditField                    matlab.ui.control.NumericEditField
        tmpEditFieldLabel               matlab.ui.control.Label
        tmnEditField                    matlab.ui.control.NumericEditField
        tmnEditFieldLabel               matlab.ui.control.Label
        preEditField                    matlab.ui.control.NumericEditField
        preEditFieldLabel               matlab.ui.control.Label
        X5_Bf_2010_DaEditField          matlab.ui.control.NumericEditField
        X5_Bf_2010_DaEditFieldLabel     matlab.ui.control.Label
        X5_Ct_2010_DaEditField          matlab.ui.control.NumericEditField
        X5_Ct_2010_DaEditFieldLabel     matlab.ui.control.Label
        dominantlandEditField           matlab.ui.control.NumericEditField
        dominantlandEditFieldLabel      matlab.ui.control.Label
        elevationEditField              matlab.ui.control.NumericEditField
        elevationEditFieldLabel         matlab.ui.control.Label
        wetEditField                    matlab.ui.control.NumericEditField
        wetEditFieldLabel               matlab.ui.control.Label
        vrapEditField                   matlab.ui.control.NumericEditField
        vrapEditFieldLabel              matlab.ui.control.Label
        petEditField                    matlab.ui.control.NumericEditField
        petEditFieldLabel               matlab.ui.control.Label
        frsEditField                    matlab.ui.control.NumericEditField
        frsEditFieldLabel               matlab.ui.control.Label
        dtrEditField                    matlab.ui.control.NumericEditField
        dtrEditFieldLabel               matlab.ui.control.Label
        cldEditField                    matlab.ui.control.NumericEditField
        cldEditFieldLabel               matlab.ui.control.Label
        ycoordinateEditField            matlab.ui.control.NumericEditField
        ycoordinateEditFieldLabel       matlab.ui.control.Label
        xcoordinateEditField            matlab.ui.control.NumericEditField
        xcoordinateEditFieldLabel       matlab.ui.control.Label
        Prediction_2                    matlab.ui.control.Label
        InfoPredictionLabel_2           matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        MoreInformationLabel_2          matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        InfoButton_LumpySkin            matlab.ui.control.StateButton
        Hyperlink_3                     matlab.ui.control.Hyperlink
        EditField_2                     matlab.ui.control.EditField
        LumpySkinTextArea               matlab.ui.control.TextArea
        DIAGNOSISButton_LumpySkin       matlab.ui.control.Button
    end

    
    properties (Access = private)
        % initalize heart diseases variables
        % General Heart Model
        HeartGen_Model % ML model for HHI
        HeartGen_symptoms % get Symptoms from HHI
        HeartGen_current_feature % array to fill for HHI

        % Exact Heart Model
        HeartEx_Model % ML Model for Heart Exact
        HeartEx_input % array to be filled by callbacks of symptoms

        % initalize general diseases variables
        General_Model % ML model for general diseases
        General_symptoms % all symptoms of general diseases
        General_current_feature % array to store values
        Diseases_General; % array with the disease names in correct order

        % initalize lumpy skin disease variables
        LS_input % array to store the input values of lumpy skin
        LS_Model; % ML model for lumpy skin

        % intialize Diabetes data variables
        Diabetes_Model; % ML Model for diabetes
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load General Disease data
            load('model.mat','OurMLmodel','unique_symptoms');
            load('unique_diseases.mat', 'un_dis');
            app.ListBox.Items = unique_symptoms; % fill Listbox with Symptoms
            app.General_Model = OurMLmodel; % allocation MLmodel to the app
            app.General_symptoms = unique_symptoms; % set symptoms
             app.Diseases_General = un_dis; % set diseases
            % initialize array; only filled with zeros
            app.General_current_feature = zeros(1,size(app.General_symptoms,1));
            % set Listbox Value to empty, so the first symptom can also be 
            % selected:
            app.ListBox.Value = {}; 
        
            % load Lumpy skin data
            load("LS_MLmodel.mat", "MLmodel_ls"); 
            % initialize array; only filled with zeros
            app.LS_input = zeros(1,16);    
            app.LS_Model = MLmodel_ls; % allocation MLmodel to the app

            %load heart exact data
            load('TrainHeartSmallModels.mat','Mdl');
            app.HeartEx_Model = Mdl; % allocation MLmodel to the app
            % initialize array; only filled with -1 except age
            app.HeartEx_input(1) = 0; % fill with according default value
            for i = 2:13
                app.HeartEx_input(i) = -1;
            end

            %load heart general data
            load('HHI_MLModel.mat','MLmodel', 'all_symptoms_hh'); %load model and symptoms 
            app.HeartGen_Model=MLmodel; % allocation MLmodel to the app
            app.HeartGen_symptoms=all_symptoms_hh; %store symptoms in our class
            % initilize array: only filled with zeros, except at index
            % 7, 14 , 20
            app.HeartGen_current_feature=zeros(1, size(app.HeartGen_symptoms,1)-1); 
            app.HeartGen_current_feature(1,7)=2; % fill with the according default value
            app.HeartGen_current_feature(1,14)=1; % fill with the according default value
            app.HeartGen_current_feature(1,18)=1; % fill with the according default value
            app.HeartGen_current_feature(1,20)=1; % fill with the according default value

            % load Diabetes data
            load("DIAModelDecisionTrees.mat", 'MLmodelTrees') % load data
            app.Diabetes_Model = MLmodelTrees; % allocation MLmodel to the app

        end

        % Button pushed function: GeneralDiseasesButton
        function GeneralDiseasesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.GeneralDiseasesTab; % Open General Diseases Tab
        end

        % Value changed function: InfoButton_General
        function InfoButton_GeneralValueChanged(app, event)
            % show Information on General Diseases when Info Button is pushed
            % make "MoreInformation" Label disappear when Info Button gets pushed
            app.MoreInformationLabel.Visible = ~app.MoreInformationLabel.Visible;
            value = app.InfoButton_General.Value;
            if value 
                app.GeneralDiseasesTextArea.Visible = "on";
            else
                app.GeneralDiseasesTextArea.Visible = "off";
            end 
        end

        % Button pushed function: HomeButtonGeneral
        function HomeButtonGeneralPushed(app, event)
            % get back to the Home Screen
            app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            % ListBox in General Diseases Tab
            value = app.ListBox.Value;
            
            % initialize showing 
            % make it dependent from already shown Items and not 'value', 
            % so nothing gets lost when using search function
            showing= app.YourselectedsymptomsListBox.Items;
            % check if items from value are not yet in showing
            % and if so, add them
            for i = 1:numel(value)
                % change class for using func.
                strValue = string(showing) 
                if ~ismember(value(i), strValue)
                    showing{end+1} = value(i);
                end
            end
            % display showing (selected Symptoms)
            s = string(showing);
            app.YourselectedsymptomsListBox.Items = s;
            if not(isempty(s))
                app.YourselectedsymptomsListBox.Visible = "on";
            end

            % set Listbox Value to empty, so the first symptom can also be 
            % selected:
            app.ListBox.Value = {};
        end

        % Value changing function: SearchEditField
        function SearchEditFieldValueChanging(app, event)
            % Searchfuntion in General Diseases Tab
            changingValue = event.Value;
            matchingElements = {};
            for i = 1:size(app.General_symptoms, 1)
                % compare search input with the elements
                if contains(app.General_symptoms(i, 1), changingValue)
                    matchingElements{end+1} = app.General_symptoms(i, 1);
                end
            end
            % show matching elements in the ListBox
            found = string(matchingElements);
            app.ListBox.Items = found;
        end

        % Double-clicked callback: YourselectedsymptomsListBox
        function YourselectedsymptomsListBoxDoubleClicked(app, event)
            % Second Listbox (displaying the chosen Symptoms) in General
            % Diseases Tab
            % Delete the items of Chosen ListBox which were Double-clicked
            item = event.InteractionInformation.Item; 
            app.YourselectedsymptomsListBox.Items(item) = [];
            % Make the box invisible if it is empty
            if isempty(app.YourselectedsymptomsListBox.Items)
                app.YourselectedsymptomsListBox.Visible = "off";
            end
            app.YourselectedsymptomsListBox.Value = {};
        end

        % Button pushed function: DIAGNOSISButton_General
        function DIAGNOSISButton_GeneralPushed(app, event)
            % Diagnosis Button of General Diseases Tab
            % Initialize app.General_current_feature
            app.General_current_feature = zeros(1,size(app.General_symptoms,1));
                 
            % Put 1 at the corresponding places of the chosen Symptoms
            if not(isempty(app.YourselectedsymptomsListBox.Items))
                for i=1:size(app.YourselectedsymptomsListBox.Items,2)
                    feature_index = find(strcmp(app.General_symptoms,app.YourselectedsymptomsListBox.Items(1,i)));
                    app.General_current_feature(1,feature_index)=1;
                end
            end

            % give output depending on prediction of ML model
            if all(app.General_current_feature == 0) % if nothing was selected
                app.Prediction.Text = "You have not selected any symptoms, therefore no disease is detected. Please select some symptoms.";
            else

                % use the MLmodel for prediction
                [predictedClass, certainty] = predict(app.General_Model ,app.General_current_feature);

                % Convert the predicted class and certainty to character vectors
                predictedClassStr = string(predictedClass);

                index = 0;
                
                for i=1:size(certainty, 2)

                    if strcmp(predictedClassStr, string(app.Diseases_General{i, 1}))
                        index = i;
                        break;
                    end
                end

                % Update the Text property of the app.Prediction label
                app.Prediction.Text = sprintf("Probability of having %s:\n%.2f%", predictedClassStr, certainty(index));
            end
        end

        % Button pushed function: DiabetesButton
        function DiabetesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DiabetesTab; % Open Diabetes Tab
        end

        % Value changed function: InfoButton_Diabetes
        function InfoButton_DiabetesValueChanged(app, event)
            % show Information on Diabetes when Info Button is pushed
            % make "ShowInfo" Label disappear when Info Button gets pushed
            app.showInfo.Visible = ~app.showInfo.Visible;
            value = app.InfoButton_Diabetes.Value;
            if value
                app.DiabetesTextArea.Visible = "on";  % show text if button is pressed
            else 
                app.DiabetesTextArea.Visible = "off"; % hide text if button is not pressed
            end
        end

        % Button pushed function: HomeButtonDiabetes
        function HomeButtonDiabetesPushed(app, event)
            % get back to the Home Screen
            app.TabGroup.SelectedTab =app.HomeTab;
        end

        % Value changed function: PregnanciesSlider
        function PregnanciesSliderValueChanged(app, event)
            % Callbacks Diabetes
            
            % show the exact value while using the slider
            app.EditFieldPreg.Value = floor(event.Value);
        end

        % Value changed function: EditFieldPreg
        function EditFieldPregValueChanged(app, event)
            % if value is entered, also change it on the slider
            value = app.EditFieldPreg.Value;
            app.PregnanciesSlider.Value = value;
        end

        % Value changed function: GlucoseSlider
        function GlucoseSliderValueChanged(app, event)
            % show the exact value while using the slider
            app.EditFieldGluc.Value = floor(event.Value);

        end

        % Value changed function: EditFieldGluc
        function EditFieldGlucValueChanged(app, event)
            % if value is entered, also change it on the slider
            value = app.EditFieldGluc.Value;
            app.GlucoseSlider.Value = value;
        end

        % Value changed function: BloodPressureSlider
        function BloodPressureSliderValueChanged(app, event)
            % show the exact value while using the slider
            app.EditFieldBP.Value = floor(event.Value);
     
        end

        % Value changed function: EditFieldBP
        function EditFieldBPValueChanged(app, event)
            % if value is entered, also change it on the slider
            value = app.EditFieldBP.Value;
            app.BloodPressureSlider.Value = value;
        end

        % Value changed function: SkinThicknessSlider
        function SkinThicknessSliderValueChanged(app, event)
            % show the exact value while using the slider
            app.EditFieldSkin.Value = floor(event.Value);

        end

        % Value changed function: EditFieldSkin
        function EditFieldSkinValueChanged(app, event)
            % if value is entered, also change it on the slider
            value = app.EditFieldSkin.Value;
            app.SkinThicknessSlider.Value = value;
        end

        % Value changed function: InsulinSlider
        function InsulinSliderValueChanged(app, event)
            % show the exact value while using the slider
            app.EditFieldInsulin.Value = floor(event.Value);

        end

        % Value changed function: EditFieldInsulin
        function EditFieldInsulinValueChanged(app, event)
            % if value is entered, also change it on the slider
            value = app.EditFieldInsulin.Value;
            app.InsulinSlider.Value = value;
        end

        % Value changed function: BMISlider
        function BMISliderValueChanged(app, event)
            % show the exact value while using the slider
            app.EditFieldBMI.Value = floor(event.Value);

        end

        % Value changed function: EditFieldBMI
        function EditFieldBMIValueChanged(app, event)
            % if value is entered, also change it on the slider
            value = app.EditFieldBMI.Value;
            app.BMISlider.Value = value;
        end

        % Value changed function: DPFSlider
        function DPFSliderValueChanged(app, event)
            % show the exact value while using the slider
            app.EditFieldDPF.Value = floor(event.Value);

        end

        % Value changed function: EditFieldDPF
        function EditFieldDPFValueChanged(app, event)
            % if value is entered, also change it on the slider
            value = app.EditFieldDPF.Value;
            app.DPFSlider.Value = value;
        end

        % Value changed function: AgeSlider
        function AgeSliderValueChanged(app, event)
            % show the exact value while using the slider
            app.EditFieldAge.Value = floor(event.Value);
        end

        % Value changed function: EditFieldAge
        function EditFieldAgeValueChanged(app, event)
            % if value is entered, also change it on the slider
            value = app.EditFieldAge.Value;
            app.AgeSlider.Value = value;
        end

        % Button pushed function: DIAGNOSISButton_Diabetes
        function DIAGNOSISButton_DiabetesPushed(app, event)
            inputData = zeros(1,8);  % initalize input array for parameters
            % fill inputData according to the entered values
            inputData(1,1) = app.EditFieldPreg.Value;
            inputData(1,2) = app.GlucoseSlider.Value;
            inputData(1,3) = app.BloodPressureSlider.Value;
            inputData(1,4) = app.SkinThicknessSlider.Value;
            inputData(1,5) = app.InsulinSlider.Value;
            inputData(1,6) = app.BMISlider.Value;
            inputData(1,7) = app.DPFSlider.Value;
            inputData(1,8) = app.AgeSlider.Value;

            % if no values were entered: no prediction
            if all(inputData == 0)
                app.PredictionDiabetes.Text = "You have not entered any values, therefore no disease is detected. Please enter some values.";
                app.PredictionDiabetes.Visible = "on";
                app.PredictionDiabetes.FontColor = '[0, 0, 0]';
            else
                % otherwise use the predict Function and output the answer
                % for uncertainty
                if(app.Diabetes_Model.ClassNames{1,1} == 1)
                    diabColumn = 1;
                    otherc = 2;
                else
                    diabColumn = 2;
                    otherc = 1;
                end

                % use the MLmodel for prediction
                [answer, cert] = predict(app.Diabetes_Model, inputData); 
                
                app.PredictionDiabetes.Text = sprintf("Probability of diabetes: %.2f", cert(1, diabColumn));
                % Output Text with Probabilities in the according color
                if(answer{1,1} == "1")
                    app.PredictionDiabetes.FontColor= '[0.80,0.42,0.42]'; %red
                else
                    app.PredictionDiabetes.FontColor= '[0.57,0.70,0.58]'; %green
                end
                app.PredictionDiabetes.Visible = "on"; % only show answer after pressing the "Diagnosis" Button
            end
        end

        % Button pushed function: HeartcomplicationsButton
        function HeartcomplicationsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HeartcomplicationsTab; % Open Heart Diseases Tab
        end

        % Value changed function: InfoButton_Heart
        function InfoButton_HeartValueChanged(app, event)
            % show Information on Heart Diseases when Info Button is pushed
            % make "ShowInfo" Label disappear when Info Button gets pushed
            app.showInfo_2.Visible = ~app.showInfo_2.Visible;
            value = app.InfoButton_Heart.Value;
            if value
                app.Heart1TextArea.Visible = "on";
            else
                app.Heart1TextArea.Visible = "off";
            end
        end

        % Button pushed function: HomeButtonHeart
        function HomeButtonHeartPushed(app, event)
            % get back to the Home Screen
            app.TabGroup.SelectedTab =app.HomeTab;
        end

        % Button pushed function: ExactButton
        function ExactButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ExactMeasurementsTab; % Open Heart Disease Exact Tab           
        end

        % Value changed function: InfoButton_HeartEx
        function InfoButton_HeartExValueChanged(app, event)
            % show Information on Heart Diseases when Info Button is pushed
            % make "ShowInfo" Label disappear when Info Button gets pushed
            app.showInfo_3.Visible = ~app.showInfo_3.Visible;
            value = app.InfoButton_HeartEx.Value;
            if value
                app.Heart2TextArea.Visible = "on";
            else
                app.Heart2TextArea.Visible = "off";
            end
        end

        % Button pushed function: HomeButtonHeartEx
        function HomeButtonHeartExPushed(app, event)
            % get back to the Home Screen
            app.TabGroup.SelectedTab =app.HomeTab;
        end

        % Button pushed function: BackButtonHeartEx
        function BackButtonHeartExPushed(app, event)
            % get back to the HeartComplications Screen
            app.TabGroup.SelectedTab = app.HeartcomplicationsTab;
        end

        % Value changed function: HeartExAgeEditField
        function HeartExAgeEditFieldValueChanged(app, event)
            % Fill array at according place when age is changed
            value = app.HeartExAgeEditField.Value;
            app.HeartEx_input(1,1) = value;
        end

        % Value changed function: thalchEditField
        function thalchEditFieldValueChanged(app, event)
            % Fill array at according place when Thalch is changed
            value = app.thalchEditField.Value;
            % throw error when unacceptable values are entered
            if -1 < value & value < 55
                errordlg('value for Thalch must be between 55 and 210, or -1','Error');
            else
                app.HeartEx_input(1,8) = value;
            end
        end

        % Value changed function: OldpeakEditField
        function OldpeakEditFieldValueChanged(app, event)
            % Fill array at according place when OldPeak is changed
            value = app.OldpeakEditField.Value;
            app.HeartEx_input(1,10) = value;
        end

        % Value changed function: CholEditField
        function CholEditFieldValueChanged(app, event)
            % Fill array at according place when Chol is changed
            value = app.CholEditField.Value;
            % throw error when unacceptable values are entered
            if -1 < value & value < 80
                errordlg('value for Chol must be between 80 and 610, or -1', 'Error');
            else
                app.HeartEx_input(1,5) = value;
            end
        end

        % Value changed function: TrestbpsEditField
        function TrestbpsEditFieldValueChanged(app, event)
            % Fill array at according place when Trestbps is changed
            value = app.TrestbpsEditField.Value;
            % throw error when unacceptable values are entered
            if -1 < value & value < 75
                errordlg('value for Trestbps must be between 75 and 205, or -1', 'Error');
            else
                app.HeartEx_input(1,4) = value;
            end 
        end

        % Selection changed function: FBSButtonGroup
        function FBSButtonGroupSelectionChanged(app, event)
            % differentiate which option was picked &
            % Fill array at according place when FBS is changed
            selectedButton = app.FBSButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'True')
                app.HeartEx_input(1,6) = 1;
            elseif strcmp(selectedButton.Text, 'False')
                app.HeartEx_input(1,6) = 0;
            else
                app.HeartEx_input(1,6) = -1;
            end
        end

        % Value changed function: caSpinner
        function caSpinnerValueChanged(app, event)
            % Fill array at according place when Ca is changed
            value = app.caSpinner.Value;
            app.HeartEx_input(1,12) = value;
        end

        % Value changed function: HeartExSexDropDown
        function HeartExSexDropDownValueChanged(app, event)
            % differentiate which option was picked &
            % Fill array at according place when Sex is changed
            value = app.HeartExSexDropDown.Value;
            if strcmp(value, 'Male')
                app.HeartEx_input(1,2) = 1;
            elseif strcmp (value, 'Female')
                app.HeartEx_input(1,2) = 0;
            else 
                app.HeartEx_input(1,2) = -1;
            end
        end

        % Value changed function: cpDropDown
        function cpDropDownValueChanged(app, event)
           % differentiate which option was picked &
           % Fill array at according place when CP is changed
            value = app.cpDropDown.Value;
            if strcmp(value, 'typical angina')
                app.HeartEx_input(1,3) = 0;
            elseif strcmp(value, 'atypical angina')
                    app.HeartEx_input(1,3) = 1;
            elseif strcmp(value, 'non-anginal')
                app.HeartEx_input(1,3) = 2;
            elseif strcmp(value, 'asymptomatic')
                app.HeartEx_input(1,3) = 3;
            else
                app.HeartEx_input(1,3) = -1;
            end
        end

        % Value changed function: ThalDropDown
        function ThalDropDownValueChanged(app, event)
            % differentiate which option was picked &
            % Fill array at according place when Thal is changed
            value = app.ThalDropDown.Value;
            if strcmp(value, 'normal')
                app.HeartEx_input(1,13) = 0;
            elseif strcmp(value, 'fixed defect')
                app.HeartEx_input(1,13) = 1;
            elseif strcmp(value, 'reversable defect')
                app.HeartEx_input(1,13) = 2;
            else
                app.HeartEx_input(1,13) = -1;
            end
        end

        % Value changed function: restecgDropDown
        function restecgDropDownValueChanged(app, event)
            % differentiate which option was picked &
            % Fill array at according place when Restecg is changed
            value = app.restecgDropDown.Value;
            if strcmp(value, 'normal')
                app.HeartEx_input(1,7) = 0;
            elseif strcmp(value, 'stt abnormality')
                app.HeartEx_input(1,7) = 1;
            elseif strcmp(value, 'lv hypertrophy')
                app.HeartEx_input(1,7) = 2;
            else
                app.HeartEx_input(1,7) = -1;
            end
        end

        % Value changed function: slopeDropDown
        function slopeDropDownValueChanged(app, event)
            % differentiate which option was picked &
            % Fill array at according place when Slope is changed
            value = app.slopeDropDown.Value;
            if strcmp(value, 'upsloping')
                app.HeartEx_input(1,11) = 0;
            elseif strcmp(value, 'flat')
                app.HeartEx_input(1,11) = 1;
            elseif strcmp(value, 'downsloping')
                app.HeartEx_input(1,11) = 2;
            else
                app.HeartEx_input(1,11) = -1;
            end
        end

        % Selection changed function: ExangButtonGroup
        function ExangButtonGroupSelectionChanged(app, event)
            % differentiate which option was picked &
            % Fill array at according place when Exang is changed
            selectedButton = app.ExangButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'True')
                app.HeartEx_input(1,9) = 1;
            elseif strcmp(selectedButton.Text, 'False')
                app.HeartEx_input(1,9) = 0;
            else
                app.HeartEx_input(1,9) = -1;
            end
        end

        % Button pushed function: DIAGNOSISButton_HeartEx
        function DIAGNOSISButton_HeartExPushed(app, event)
            % Diagnosis button of Heart Exact Tab
            % If no values entered: output text saying no prediction was
            % possible in black color
            if app.HeartEx_input == [0 -1 -1 -1 -1 -1 -1 -1 -1 -1 -1 -1 -1]
                app.HeartExPredictionLabel.Text = "You have not entered any values, therefore no disease is detected. Please enter some values.";
                app.HeartExPredictionLabel.Visible = "on";
                app.HeartExPredictionLabel.FontColor = '[0, 0, 0]';
            else

                % for the uncertainty
                if(app.HeartEx_Model.ClassNames{1,1} == 1)
                    HeartExColumn = 1;
                    otherc = 2;
                else
                    HeartExColumn = 2;
                    otherc = 1;
                end

                % use the MLmodel for prediction
                [answer, cert] = predict(app.HeartEx_Model, app.HeartEx_input); 

                app.HeartExPredictionLabel.Text = sprintf("Probability of heart disease: %.2f", cert(1, HeartExColumn));
                % Output Text with Probabilities in the according color
                if(answer{1,1} == "1")
                    app.HeartExPredictionLabel.FontColor = '[0.80,0.42,0.42]'; %red
                else
                    app.HeartExPredictionLabel.FontColor = '[0.57,0.70,0.58]'; %green
                end

                % only show answer after pressing the "Diagnosis" Button
                app.HeartExPredictionLabel.Visible = "on"; 

            end
        end

        % Button pushed function: GeneralButton
        function GeneralButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.GeneralMeasurementsTab; % Open Heart Disease General Tab  
        end

        % Value changed function: InfoButton_HeartGen
        function InfoButton_HeartGenValueChanged(app, event)
            % Show information on Heart Diseases when Info button is pushed
            value = app.InfoButton_HeartGen.Value;
            if value
                app.Heart2TextArea.Visible = "on";
            else
                app.Heart2TextArea.Visible = "off";
            end
            % make symptoms, that are in the same spot as the Info field,
            % disappear when the info Button is pushed
            app.AlcoholconsumptionCheckBox.Visible = ~app.AlcoholconsumptionCheckBox.Visible;
            app.SmokingCheckBox.Visible = ~app.SmokingCheckBox.Visible;
            app.DifficultyWalkingCheckBox.Visible = ~app.DifficultyWalkingCheckBox.Visible;
            app.ExercisedCheckBox.Visible = ~app.ExercisedCheckBox.Visible;
            app.FruitCheckBox.Visible = ~app.FruitCheckBox.Visible;
            app.VegetableCheckBox.Visible = ~app.VegetableCheckBox.Visible;
            app.HeartGenTextArea.Visible = ~app.HeartGenTextArea.Visible;
        end

        % Button pushed function: HomeButtonHeartGen
        function HomeButtonHeartGenPushed(app, event)
            % get back to the Home Screen
            app.TabGroup.SelectedTab =app.HomeTab;
        end

        % Button pushed function: BackButtonHeartGen
        function BackButtonHeartGenPushed(app, event)
            % get back to the HeartComplications Screen
            app.TabGroup.SelectedTab = app.HeartcomplicationsTab;
        end

        % Value changed function: SexDropDown
        function SexDropDownValueChanged(app, event)
            value = app.SexDropDown.Value;
            % differentiate which option was picked
            switch value 
                case 'Male'
                    option = 1;
                case 'Female'
                    option = 0;
            end
            % fill array with the corresponding number
            app.HeartGen_current_feature(1, 18)= option;
        end

        % Value changed function: GradeDropDown
        function GradeDropDownValueChanged(app, event)
            value = app.GradeDropDown.Value;
            % differentiate which option was picked
            switch value
                case 'No school or only kindergarten'
                    option = 1;
                case 'Elementary'
                    option = 2;
                case 'Grades 9 through 11'
                    option = 3;
                case 'Grade 12 or GED'
                    option = 4;
                case 'College 1-3 years'
                    option = 5;
                case 'College for at least 4 years'
                    option = 6;
            end
            % fill array with the corresponding number
            app.HeartGen_current_feature(1, 20)= option;
        end

        % Value changed function: DiabetesDropDown
        function DiabetesDropDownValueChanged(app, event)
            value = app.DiabetesDropDown.Value;
            % differentiate which option was picked
            switch value
                case 'Yes (outside of pregnancy)'
                    option = 2;
                case 'No'
                    option = 0;
                case 'Borderline'
                    option = 1;
            end
            % fill array with the corresponding number
            app.HeartGen_current_feature(1, 7)= option;
        end

        % Value changed function: GeneralHealthDropDown
        function GeneralHealthDropDownValueChanged(app, event)
            value = app.GeneralHealthDropDown.Value;
            % differentiate which option was picked
            switch value
                case 'Excellent'
                    option = 1;
                case 'Very good'
                    option = 2;
                case 'Good'
                    option = 3;
                case 'Fair'
                    option = 4;
                case 'Poor'
                    option = 5;
            end
            % fill array with the corresponding number
            app.HeartGen_current_feature(1, 14)= option;
        end

        % Value changed function: AgeEditField
        function AgeEditFieldValueChanged(app, event)
            value = app.AgeEditField.Value;
            % assign the input to its class
            if value == 0
                option = 0;
            elseif value <= 25
                option = 1;
            elseif value <= 29
                option = 2;
            elseif value <= 34
                option = 3;
            elseif value <= 39
                option = 4;
            elseif value <= 44
                option = 5;
            elseif value <= 49
                option = 6;
            elseif value <= 54
                option = 7;
            elseif value <= 59
                option = 8;
            elseif value <= 64
                option = 9;
            elseif value <= 69
                option = 10;
            elseif value <= 74
                option = 11;
            elseif value <= 79
                option = 12;
            else
                option = 13;
            end 
            % fill array with the corresponding number
            app.HeartGen_current_feature(1,19) = option;
        end

        % Value changed function: BMIEditField
        function BMIEditFieldValueChanged(app, event)
            % pass on the BMI
            value = app.BMIEditField.Value;
            app.HeartGen_current_feature(1, 4) = value; 
        end

        % Value changed function: MentalhealthEditField
        function MentalhealthEditFieldValueChanged(app, event)
            % pass on the number of days
            value = app.MentalhealthEditField.Value;
            app.HeartGen_current_feature(1, 15) = value; 
        end

        % Value changed function: PhysicalhealthEditField
        function PhysicalhealthEditFieldValueChanged(app, event)
            % pass on the number of days
            value = app.PhysicalhealthEditField.Value;
            app.HeartGen_current_feature(1, 16) = value; 
        end

        % Value changed function: IncomeEditField
        function IncomeEditFieldValueChanged(app, event)
            value = app.IncomeEditField.Value;
            % assign the input to its class
            if value == 0
                option = 0;
            elseif value < 10000
                option = 1;
            elseif value < 15000
                option = 2;
            elseif value < 20000
                option = 3;
            elseif value < 25000
                option = 4;
            elseif value < 35000
                option = 5;
            elseif value < 50000
                option = 6;
            elseif value < 75000
                option = 7;
            elseif value >= 75000
                option = 8;
            end 
            % fill array with the corresponding number
            app.HeartGen_current_feature(1,21) = option;
        end

        % Value changed function: AlcoholconsumptionCheckBox
        function AlcoholconsumptionCheckBoxValueChanged(app, event)
            % pass on 1(yes) or 0(no)
            value = app.AlcoholconsumptionCheckBox.Value;
            app.HeartGen_current_feature(1, 11) = value; 
        end

        % Value changed function: SmokingCheckBox
        function SmokingCheckBoxValueChanged(app, event)
            % pass on 1(yes) or 0(no)
            value = app.SmokingCheckBox.Value;
            app.HeartGen_current_feature(1, 5) = value; 
        end

        % Value changed function: DifficultyWalkingCheckBox
        function DifficultyWalkingCheckBoxValueChanged(app, event)
            % pass on 1(yes) or 0(no)
            value = app.DifficultyWalkingCheckBox.Value;
            app.HeartGen_current_feature(1, 17) = value; 
        end

        % Value changed function: ExercisedCheckBox
        function ExercisedCheckBoxValueChanged(app, event)
            % pass on 1(yes) or 0(no)
            value = app.ExercisedCheckBox.Value;
            app.HeartGen_current_feature(1, 8) = value; 
        end

        % Value changed function: FruitCheckBox
        function FruitCheckBoxValueChanged(app, event)
            % pass on 1(yes) or 0(no)
            value = app.FruitCheckBox.Value;
            app.HeartGen_current_feature(1, 9) = value;
        end

        % Value changed function: VegetableCheckBox
        function VegetableCheckBoxValueChanged(app, event)
            % pass on 1(yes) or 0(no)
            value = app.VegetableCheckBox.Value;
            app.HeartGen_current_feature(1, 10) = value;
        end

        % Value changed function: BloodcholersterolCheckBox
        function BloodcholersterolCheckBoxValueChanged(app, event)
            % pass on 1(yes) or 0(no)
            value = app.BloodcholersterolCheckBox.Value;
            app.HeartGen_current_feature(1, 2) = value;
        end

        % Value changed function: BloodcholesterolCheckupCheckBox
        function BloodcholesterolCheckupCheckBoxValueChanged(app, event)
            % pass on 1(yes) or 0(no)
            value = app.BloodcholesterolCheckupCheckBox.Value;
            app.HeartGen_current_feature(1, 3) = value;
        end

        % Value changed function: StrokeCheckBox
        function StrokeCheckBoxValueChanged(app, event)
            % pass on 1(yes) or 0(no)
            value = app.StrokeCheckBox.Value;
            app.HeartGen_current_feature(1, 6) = value;
        end

        % Value changed function: HighbloodpressureCheckBox
        function HighbloodpressureCheckBoxValueChanged(app, event)
            % pass on 1(yes) or 0(no)
            value = app.HighbloodpressureCheckBox.Value;
            app.HeartGen_current_feature(1, 1) = value;
        end

        % Value changed function: HealthcarecoverageCheckBox
        function HealthcarecoverageCheckBoxValueChanged(app, event)
            % pass on 1(yes) or 0(no)
            value = app.HealthcarecoverageCheckBox.Value;
            app.HeartGen_current_feature(1, 12) = value;
        end

        % Value changed function: DoctorssvisitiCostsCheckBox
        function DoctorssvisitiCostsCheckBoxValueChanged(app, event)
            % pass on 1(yes) or 0(no)
            value = app.DoctorssvisitiCostsCheckBox.Value;
            app.HeartGen_current_feature(1, 13) = value;
        end

        % Button pushed function: DIAGNOSISButton_HeartGen
        function DIAGNOSISButton_HeartGenPushed(app, event)
            % Diagnosis Button of Heart General Tab
            % No values entered --> no prediction
            if app.HeartGen_current_feature == [0 0 0 0 0 0 2 0 0 0 0 0 0 1 0 0 0 1 0 1 0]
                app.Prediction_3.Text = "You have not entered any values, therefore no disease is detected. Please enter some values.";
                app.Prediction_3.Visible = "on";
                app.Prediction_3.FontColor = '[0, 0, 0]'; % Text in black
            else
                % for uncertainty
                if(app.HeartGen_Model.ClassNames(1,1) == 1)
                    HeartGenColumn = 1;
                    otherc = 2;
                else
                    HeartGenColumn = 2;
                    otherc = 1;
                end
                % use the MLmodel for prediction
                [answer, cert] = predict(app.HeartGen_Model, app.HeartGen_current_feature);
                app.Prediction_3.Text = sprintf("Probability of heart disease: %.2f", cert(1, HeartGenColumn));
     
                % Output Text with Probabilities in the according color
                if(answer(1,1) == 1)
                    app.Prediction_3.FontColor = '[0.80,0.42,0.42]'; % red
                else
                    app.Prediction_3.FontColor = '[0.57,0.70,0.58]'; % green
                end
                % only show answer after pressing the "Diagnosis" Button
                app.Prediction_3.Visible = "on"; 
            end
        end

        % Button pushed function: LumpyskinButton
        function LumpyskinButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.LumpyskinTab; % Open Lumpy Skin Disease Tab            
        end

        % Value changed function: InfoButton_LumpySkin
        function InfoButton_LumpySkinValueChanged(app, event)
            % show Information on Lumpy Skin when Info Button is pushed
            % make "MoreInformation" Label disappear when Info Button gets pushed
            app.MoreInformationLabel_2.Visible = ~app.MoreInformationLabel_2.Visible;
            value = app.InfoButton_LumpySkin.Value;
            if value
                app.LumpySkinTextArea.Visible = "on";
            else
                app.LumpySkinTextArea.Visible = "off";
            end
        end

        % Button pushed function: HomeButtonLumpySkin
        function HomeButtonLumpySkinPushed(app, event)
            % get back to the Home Screen
            app.TabGroup.SelectedTab =app.HomeTab;
        end

        % Value changed function: xcoordinateEditField
        function xcoordinateEditFieldValueChanged(app, event)
            % Callback functions for Lumpy Skin
            
            % store x-value in array for prediction
            x_value = app.xcoordinateEditField.Value;
            app.LS_input(1,1) = x_value;
        end

        % Value changed function: ycoordinateEditField
        function ycoordinateEditFieldValueChanged(app, event)
            % store y-value in array for prediction
            y_value = app.ycoordinateEditField.Value;
            app.LS_input(1, 2) = y_value;
        end

        % Value changed function: cldEditField
        function cldEditFieldValueChanged(app, event)
            % store cld-value in array for prediction
            cld_value = app.cldEditField.Value;
            app.LS_input(1, 3) = cld_value;
        end

        % Value changed function: dtrEditField
        function dtrEditFieldValueChanged(app, event)
            % store dtr-value in array for prediction
            dtr_value = app.dtrEditField.Value;
            app.LS_input(1, 4) = dtr_value;
        end

        % Value changed function: frsEditField
        function frsEditFieldValueChanged(app, event)
            % store frs-value in array for prediction
            frs_value = app.frsEditField.Value;
            app.LS_input(1, 5) = frs_value;
        end

        % Value changed function: petEditField
        function petEditFieldValueChanged(app, event)
            % store pet-value in array for prediction
            pet_value = app.petEditField.Value;
            app.LS_input(1, 6) = pet_value;
        end

        % Value changed function: preEditField
        function preEditFieldValueChanged(app, event)
            % store pre-value in array for prediction
            pre_value = app.preEditField.Value;
            app.LS_input(1, 7) = pre_value;
        end

        % Value changed function: tmnEditField
        function tmnEditFieldValueChanged(app, event)
            % store tmn-value in array for prediction
            tmn_value = app.tmnEditField.Value;
            app.LS_input(1, 8) = tmn_value;
        end

        % Value changed function: tmpEditField
        function tmpEditFieldValueChanged(app, event)
            % store tmp-value in array for prediction
            tmp_value = app.tmpEditField.Value;
            app.LS_input(1, 9) = tmp_value;
        end

        % Value changed function: tmxEditField
        function tmxEditFieldValueChanged(app, event)
            % store tmx-value in array for prediction
            tmx_value = app.tmxEditField.Value;
            app.LS_input(1, 10) = tmx_value;
        end

        % Value changed function: vrapEditField
        function vrapEditFieldValueChanged(app, event)
            % store vrap-value in array for prediction
            vrap_value = app.vrapEditField.Value;
            app.LS_input(1, 11) = vrap_value;
        end

        % Value changed function: wetEditField
        function wetEditFieldValueChanged(app, event)
            % store wet-value in array for prediction
            wet_value = app.wetEditField.Value;
            app.LS_input(1, 12) = wet_value;
        end

        % Value changed function: elevationEditField
        function elevationEditFieldValueChanged(app, event)
            % store ele-value in array for prediction
            ele_value = app.elevationEditField.Value;
            app.LS_input(1, 13) = ele_value;
        end

        % Value changed function: dominantlandEditField
        function dominantlandEditFieldValueChanged(app, event)
            % store dlc-value in array for prediction
            dlc_value = app.dominantlandEditField.Value;
            app.LS_input(1, 14) = dlc_value;
        end

        % Value changed function: X5_Ct_2010_DaEditField
        function X5_Ct_2010_DaEditFieldValueChanged(app, event)
            % store X5_Ct-value in array for prediction
            X5_Ct_value = app.X5_Ct_2010_DaEditField.Value;
            app.LS_input(1, 15) = X5_Ct_value;
        end

        % Value changed function: X5_Bf_2010_DaEditField
        function X5_Bf_2010_DaEditFieldValueChanged(app, event)
            % store X5_Bf-value in array for prediction
            X5_Bf_value = app.X5_Bf_2010_DaEditField.Value;
            app.LS_input(1, 16) = X5_Bf_value;
        end

        % Button pushed function: DIAGNOSISButton_LumpySkin
        function DIAGNOSISButton_LumpySkinPushed(app, event)
            % Diagnosis Button of Lumpy Skin Tab
            % If nothins was entered: no prediction is possible
            if all(app.LS_input == 0)
                app.Prediction_2.Text = "You have not entered any values, therefore no disease is detected. Please enter some values.";
                app.Prediction_2.Visible = "on";
                app.Prediction_2.FontColor = '[0, 0, 0]';
            else
                % if values were entered: use predict function
                % for uncertainty
                if(app.LS_Model.ClassNames{1,1} == 1)
                    LSColumn = 1;
                    otherc = 2;
                else
                    LSColumn = 2;
                    otherc = 1;
                end

                % use the MLmodel for prediction
                [answer, cert] = predict(app.LS_Model, app.LS_input);
                % display text and certainty
                app.Prediction_2.Text = sprintf("Probability of lumpy skin: %.2f", cert(1, LSColumn));
                % Output Text with Probabilities in the according color
                if(answer{1,1} == "1")
                    app.Prediction_2.FontColor= '[0.80,0.42,0.42]'; %red
                else
                    app.Prediction_2.FontColor= '[0.57,0.70,0.58]'; %green
                end
                app.Prediction_2.Visible = "on"; % only show answer after pressing the "Diagnosis" Button

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.GeneralDiseasesButton = uibutton(app.HomeTab, 'push');
            app.GeneralDiseasesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralDiseasesButtonPushed, true);
            app.GeneralDiseasesButton.Icon = fullfile(pathToMLAPP, 'Virus.png');
            app.GeneralDiseasesButton.Text = 'GeneralDiseases';
            app.DiabetesButton = uibutton(app.HomeTab, 'push');
            app.DiabetesButton.ButtonPushedFcn = createCallbackFcn(app, @DiabetesButtonPushed, true);
            app.DiabetesButton.Icon = fullfile(pathToMLAPP, 'Bonbon.png');
            app.DiabetesButton.Text = 'Diabetes';
            app.HeartcomplicationsButton = uibutton(app.HomeTab, 'push');
            app.HeartcomplicationsButton.ButtonPushedFcn = createCallbackFcn(app, @HeartcomplicationsButtonPushed, true);
            app.HeartcomplicationsButton.Icon = fullfile(pathToMLAPP, 'Herz.png');
            app.HeartcomplicationsButton.Text = 'Heartcomplications';
            app.LumpyskinButton = uibutton(app.HomeTab, 'push');
            app.LumpyskinButton.ButtonPushedFcn = createCallbackFcn(app, @LumpyskinButtonPushed, true);
            app.LumpyskinButton.Icon = fullfile(pathToMLAPP, 'Kuh.png');
            app.LumpyskinButton.Text = 'Lumpyskin';
            app.SelectHomeLabel = uilabel(app.HomeTab);
            app.SelectHomeLabel.Text = 'Please Select what you want to examine your patient for:';
            app.WelcomeLabel = uilabel(app.HomeTab);
            app.WelcomeLabel.Text = 'Welcome!';
            app.Step1Label = uilabel(app.HomeTab);
            app.Step1Label.Text = 'Select the disease to be examined';
            app.ToDo1Label = uilabel(app.HomeTab);
            app.ToDo1Label.Text = 'To Do: Press one of the buttons bellow!';
            app.Step2Label = uilabel(app.HomeTab);
            app.Step2Label.Text = 'Enter the disease-specific values ';
            app.ToDo2Label = uilabel(app.HomeTab);
            app.ToDo2Label.Text = {'To Do: Go to the corresponding tab'; 'and set the patients values.'};
            app.Step3Label = uilabel(app.HomeTab);
            app.Step3Label.Text = 'Get the prediction';
            app.ToDo3Label = uilabel(app.HomeTab);
            app.ToDo3Label.Text = 'To Do: Press the "DIAGNOSIS" Button!';
            app.ImageThree = uiimage(app.HomeTab);
            app.ImageThree.ImageSource = fullfile(pathToMLAPP, 'IMG_8076.png');
            app.ImageTwo = uiimage(app.HomeTab);
            app.ImageTwo.ImageSource = fullfile(pathToMLAPP, 'IMG_8075.png');
            app.ImageOne = uiimage(app.HomeTab);
            app.ImageOne.ImageSource = fullfile(pathToMLAPP, 'IMG_8074.png');
            app.NamesLabel = uilabel(app.HomeTab);
            app.NamesLabel.Text = 'Mariella, Ricarda, Laura, Sophie | 2023';
            app.Image23 = uiimage(app.HomeTab);
            app.Image23.ImageSource = fullfile(pathToMLAPP, 'HomeBild.jpg');
            app.GeneralDiseasesTab = uitab(app.TabGroup);
            app.GeneralDiseasesTab.Title = 'GeneralDiseases';
            app.DIAGNOSISButton_General = uibutton(app.GeneralDiseasesTab, 'push');
            app.DIAGNOSISButton_General.ButtonPushedFcn = createCallbackFcn(app, @DIAGNOSISButton_GeneralPushed, true);
            app.DIAGNOSISButton_General.Text = 'DIAGNOSIS';
            app.GeneralDiseasesTextArea = uitextarea(app.GeneralDiseasesTab);
            app.GeneralDiseasesTextArea.Visible = 'off';
            app.GeneralDiseasesTextArea.Value = {'Diseases can get very dangerous if it is not treated properly. Therefore, even if it is not a certain guarantee but only a probable prediction, take the predicted disease seriously and inform yourself further about it!'};
            app.EditField = uieditfield(app.GeneralDiseasesTab, 'text');
            app.EditField.Value = '🔍  For further information click here  >>>  ';
            app.InfoButton_General = uibutton(app.GeneralDiseasesTab, 'state');
            app.InfoButton_General.ValueChangedFcn = createCallbackFcn(app, @InfoButton_GeneralValueChanged, true);
            app.InfoButton_General.Icon = fullfile(pathToMLAPP, 'Teamprojekt Infobutton.png');
            app.InfoButton_General.Text = '';
            app.Label = uilabel(app.GeneralDiseasesTab);
            app.Label.Tooltip = {'dominant land cover'};
            app.Label.Text = '';
            app.MoreInformationLabel = uilabel(app.GeneralDiseasesTab);
            app.MoreInformationLabel.Text = 'For more information press the button!';
            app.InfoPredictionLabel = uilabel(app.GeneralDiseasesTab);
            app.InfoPredictionLabel.Text = 'Press button for the prediction:';
            app.ListBox = uilistbox(app.GeneralDiseasesTab);
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Item 1'};
            app.Prediction = uilabel(app.GeneralDiseasesTab);
            app.Prediction.Text = '';
            app.SearchforsymptomEditFieldLabel = uilabel(app.GeneralDiseasesTab);
            app.SearchforsymptomEditFieldLabel.Text = '🔍 Search for symptom:';
            app.SearchEditField = uieditfield(app.GeneralDiseasesTab, 'text');
            app.SearchEditField.ValueChangingFcn = createCallbackFcn(app, @SearchEditFieldValueChanging, true);
            app.Hyperlink_General = uihyperlink(app.GeneralDiseasesTab);
            app.Hyperlink_General.URL = 'https://www.nhsinform.scot/illnesses-and-conditions/a-to-z';
            app.Hyperlink_General.Text = 'diseases from A to Z';
            app.SelectionLabel2 = uilabel(app.GeneralDiseasesTab);
            app.SelectionLabel2.Text = '► Please select your symptoms below:';
            app.SelectSymptomLabel = uilabel(app.GeneralDiseasesTab);
            app.SelectSymptomLabel.Text = 'Click on a Symptom to select it';
            app.HomeButtonGeneral = uibutton(app.GeneralDiseasesTab, 'push');
            app.HomeButtonGeneral.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonGeneralPushed, true);
            app.HomeButtonGeneral.Icon = fullfile(pathToMLAPP, 'Bildschirmfoto 2023-06-27 um 12.51.10.png');
            app.HomeButtonGeneral.Text = '';
            app.Imageclean = uiimage(app.GeneralDiseasesTab);
            app.Imageclean.ImageSource = fullfile(pathToMLAPP, 'Hygiene2.png');
            app.ImageWashing = uiimage(app.GeneralDiseasesTab);
            app.ImageWashing.ImageSource = fullfile(pathToMLAPP, 'Hygiene3.png');
            app.ImageCough = uiimage(app.GeneralDiseasesTab);
            app.ImageCough.ImageSource = fullfile(pathToMLAPP, 'Hygiene4.png');
            app.ImageWindow = uiimage(app.GeneralDiseasesTab);
            app.ImageWindow.ImageSource = fullfile(pathToMLAPP, 'Hygiene5.png');
            app.ImageWashHands = uiimage(app.GeneralDiseasesTab);
            app.ImageWashHands.ImageSource = fullfile(pathToMLAPP, 'Hygiene1.png');
            app.WashhandsLabel = uilabel(app.GeneralDiseasesTab);
            app.WashhandsLabel.Text = {'Wash your '; 'hands!'};
            app.CleanLabel = uilabel(app.GeneralDiseasesTab);
            app.CleanLabel.Text = {'Clean'; 'regulary!'};
            app.WashLabel = uilabel(app.GeneralDiseasesTab);
            app.WashLabel.Text = {'Wash hot'; 'enough!'};
            app.CoughLabel = uilabel(app.GeneralDiseasesTab);
            app.CoughLabel.Text = {'Cough'; 'properly!'};
            app.VentilaterLabel = uilabel(app.GeneralDiseasesTab);
            app.VentilaterLabel.Text = {'Ventilate'; 'regulary!'};
            app.YourselectedsymptomsListBoxLabel = uilabel(app.GeneralDiseasesTab);
            app.YourselectedsymptomsListBoxLabel.Text = 'Your selected symptoms:';
            app.YourselectedsymptomsListBox = uilistbox(app.GeneralDiseasesTab);
            app.YourselectedsymptomsListBox.Items = {};
            app.YourselectedsymptomsListBox.Visible = 'off';
            app.YourselectedsymptomsListBox.DoubleClickedFcn = createCallbackFcn(app, @YourselectedsymptomsListBoxDoubleClicked, true);
            app.YourselectedsymptomsListBox.Value = {};
            app.DeleteSymptomLabel = uilabel(app.GeneralDiseasesTab);
            app.DeleteSymptomLabel.Text = 'Double-Click on a selected Symptom to delete it';
            app.DiabetesTab = uitab(app.TabGroup);
            app.DiabetesTab.Title = 'Diabetes';
            app.ImageBloodPressure = uiimage(app.DiabetesTab);
            app.ImageBloodPressure.ImageSource = fullfile(pathToMLAPP, 'BloodPressure.png');
            app.ImageGlucose = uiimage(app.DiabetesTab);
            app.ImageGlucose.ImageSource = fullfile(pathToMLAPP, 'Glucose.png');
            app.ImageSkinThickness = uiimage(app.DiabetesTab);
            app.ImageSkinThickness.ImageSource = fullfile(pathToMLAPP, 'SkinThickness.png');
            app.ImageAge = uiimage(app.DiabetesTab);
            app.ImageAge.ImageSource = fullfile(pathToMLAPP, 'Age.png');
            app.ImageInsulin = uiimage(app.DiabetesTab);
            app.ImageInsulin.ImageSource = fullfile(pathToMLAPP, 'Insulin.png');
            app.ImageBMI = uiimage(app.DiabetesTab);
            app.ImageBMI.ImageSource = fullfile(pathToMLAPP, 'BMI.png');
            app.InfoPredictionLabel_4 = uilabel(app.DiabetesTab);
            app.InfoPredictionLabel_4.Text = 'Press button for the prediction:';
            app.ImageDPF = uiimage(app.DiabetesTab);
            app.ImageDPF.ImageSource = fullfile(pathToMLAPP, 'DPF.png');
            app.InfoButton_Diabetes = uibutton(app.DiabetesTab, 'state');
            app.InfoButton_Diabetes.ValueChangedFcn = createCallbackFcn(app, @InfoButton_DiabetesValueChanged, true);
            app.InfoButton_Diabetes.Icon = fullfile(pathToMLAPP, 'Teamprojekt Infobutton.png');
            app.InfoButton_Diabetes.Text = '';
            app.DiabetesTextArea = uitextarea(app.DiabetesTab);
            app.DiabetesTextArea.Visible = 'off';
            app.DiabetesTextArea.Value = {'"Diabetes is a chronic disease that occurs either when the pancreas does not produce enough insulin or when the body cannot effectively use the insulin it produces. Insulin is a hormone that regulates blood glucose. Hyperglycaemia, also called raised blood glucose or raised blood sugar, is a common effect of uncontrolled diabetes and over time leads to serious damage to many of the body''s systems, especially the nerves and blood vessels"'};
            app.InfoPregnancies = uilabel(app.DiabetesTab);
            app.InfoPregnancies.Tooltip = {'Number of patients pregnancies'};
            app.InfoPregnancies.Text = '';
            app.InfoGlucose = uilabel(app.DiabetesTab);
            app.InfoGlucose.Tooltip = {'Blood concentration in (mg/dl)'};
            app.InfoGlucose.Text = '';
            app.InfoBloodPressure = uilabel(app.DiabetesTab);
            app.InfoBloodPressure.Tooltip = {'In mmHg'};
            app.InfoBloodPressure.Text = '';
            app.InfoSkinThickness = uilabel(app.DiabetesTab);
            app.InfoSkinThickness.Tooltip = {'Patients meassured SkinThickness '};
            app.InfoSkinThickness.Text = '';
            app.InfoInsulin = uilabel(app.DiabetesTab);
            app.InfoInsulin.Tooltip = {'Insulin units (mg/dl)'};
            app.InfoInsulin.Text = '';
            app.InfoBMI = uilabel(app.DiabetesTab);
            app.InfoBMI.Tooltip = {'Body Mass Index (BMI = kg/m^2)'};
            app.InfoBMI.Text = '';
            app.InfoDPF = uilabel(app.DiabetesTab);
            app.InfoDPF.Tooltip = {'DiabetesPedigreeFunction (scores likelihood of diabetes based on family)'};
            app.InfoDPF.Text = '';
            app.infoAge = uilabel(app.DiabetesTab);
            app.infoAge.Tooltip = {'Patiens age in years'};
            app.infoAge.Text = '';
            app.showInfo = uilabel(app.DiabetesTab);
            app.showInfo.Text = 'For more information press the button!';
            app.EditFieldPreg = uieditfield(app.DiabetesTab, 'numeric');
            app.EditFieldPreg.Limits = [0 20];
            app.EditFieldPreg.RoundFractionalValues = 'on';
            app.EditFieldPreg.ValueChangedFcn = createCallbackFcn(app, @EditFieldPregValueChanged, true);
            app.EditFieldGluc = uieditfield(app.DiabetesTab, 'numeric');
            app.EditFieldGluc.Limits = [0 200];
            app.EditFieldGluc.RoundFractionalValues = 'on';
            app.EditFieldGluc.ValueChangedFcn = createCallbackFcn(app, @EditFieldGlucValueChanged, true);
            app.EditFieldBP = uieditfield(app.DiabetesTab, 'numeric');
            app.EditFieldBP.Limits = [0 200];
            app.EditFieldBP.RoundFractionalValues = 'on';
            app.EditFieldBP.ValueChangedFcn = createCallbackFcn(app, @EditFieldBPValueChanged, true);
            app.EditFieldSkin = uieditfield(app.DiabetesTab, 'numeric');
            app.EditFieldSkin.Limits = [0 80];
            app.EditFieldSkin.RoundFractionalValues = 'on';
            app.EditFieldSkin.ValueChangedFcn = createCallbackFcn(app, @EditFieldSkinValueChanged, true);
            app.EditFieldInsulin = uieditfield(app.DiabetesTab, 'numeric');
            app.EditFieldInsulin.Limits = [0 500];
            app.EditFieldInsulin.RoundFractionalValues = 'on';
            app.EditFieldInsulin.ValueChangedFcn = createCallbackFcn(app, @EditFieldInsulinValueChanged, true);
            app.EditFieldBMI = uieditfield(app.DiabetesTab, 'numeric');
            app.EditFieldBMI.Limits = [0 50];
            app.EditFieldBMI.RoundFractionalValues = 'on';
            app.EditFieldBMI.ValueChangedFcn = createCallbackFcn(app, @EditFieldBMIValueChanged, true);
            app.EditFieldDPF = uieditfield(app.DiabetesTab, 'numeric');
            app.EditFieldDPF.Limits = [0 3];
            app.EditFieldDPF.ValueChangedFcn = createCallbackFcn(app, @EditFieldDPFValueChanged, true);
            app.EditFieldAge = uieditfield(app.DiabetesTab, 'numeric');
            app.EditFieldAge.Limits = [0 100];
            app.EditFieldAge.RoundFractionalValues = 'on';
            app.EditFieldAge.ValueChangedFcn = createCallbackFcn(app, @EditFieldAgeValueChanged, true);
            app.GlucoseSliderLabel = uilabel(app.DiabetesTab);
            app.GlucoseSliderLabel.Tooltip = {'Blood concentration in (mg/dl)'};
            app.GlucoseSliderLabel.Text = 'Glucose';
            app.GlucoseSlider = uislider(app.DiabetesTab);
            app.GlucoseSlider.Limits = [0 200];
            app.GlucoseSlider.MajorTicks = [0 50 100 150 200];
            app.GlucoseSlider.ValueChangedFcn = createCallbackFcn(app, @GlucoseSliderValueChanged, true);
            app.GlucoseSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200];
            app.BloodPressureSliderLabel = uilabel(app.DiabetesTab);
            app.BloodPressureSliderLabel.Tooltip = {'in mmHg'};
            app.BloodPressureSliderLabel.Text = 'BloodPressure';
            app.BloodPressureSlider = uislider(app.DiabetesTab);
            app.BloodPressureSlider.Limits = [0 200];
            app.BloodPressureSlider.MajorTicks = [0 50 100 150 200];
            app.BloodPressureSlider.ValueChangedFcn = createCallbackFcn(app, @BloodPressureSliderValueChanged, true);
            app.BloodPressureSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200];
            app.SkinThicknessSliderLabel = uilabel(app.DiabetesTab);
            app.SkinThicknessSliderLabel.Tooltip = {'Patients meassured skin thickness'};
            app.SkinThicknessSliderLabel.Text = 'SkinThickness';
            app.SkinThicknessSlider = uislider(app.DiabetesTab);
            app.SkinThicknessSlider.Limits = [0 80];
            app.SkinThicknessSlider.MajorTicks = [0 20 40 60 80];
            app.SkinThicknessSlider.ValueChangedFcn = createCallbackFcn(app, @SkinThicknessSliderValueChanged, true);
            app.SkinThicknessSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.InsulinSliderLabel = uilabel(app.DiabetesTab);
            app.InsulinSliderLabel.Tooltip = {'insulin units (mg/dl)'};
            app.InsulinSliderLabel.Text = 'Insulin';
            app.InsulinSlider = uislider(app.DiabetesTab);
            app.InsulinSlider.Limits = [0 500];
            app.InsulinSlider.ValueChangedFcn = createCallbackFcn(app, @InsulinSliderValueChanged, true);
            app.InsulinSlider.MinorTicks = [0 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425 450 475 500];
            app.BMISliderLabel = uilabel(app.DiabetesTab);
            app.BMISliderLabel.Tooltip = {'Body Mass Index (BMI = kg/m^2)'};
            app.BMISliderLabel.Text = 'BMI';
            app.BMISlider = uislider(app.DiabetesTab);
            app.BMISlider.Limits = [0 50];
            app.BMISlider.MajorTicks = [0 10 20 30 40 50];
            app.BMISlider.ValueChangedFcn = createCallbackFcn(app, @BMISliderValueChanged, true);
            app.BMISlider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50];
            app.AgeSliderLabel = uilabel(app.DiabetesTab);
            app.AgeSliderLabel.Tooltip = {'patients age in years'};
            app.AgeSliderLabel.Text = 'Age';
            app.AgeSlider = uislider(app.DiabetesTab);
            app.AgeSlider.MajorTicks = [0 20 40 60 80 100];
            app.AgeSlider.ValueChangedFcn = createCallbackFcn(app, @AgeSliderValueChanged, true);
            app.AgeSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.PregnanciesSliderLabel = uilabel(app.DiabetesTab);
            app.PregnanciesSliderLabel.Tooltip = {'number of patients pragnancies'};
            app.PregnanciesSliderLabel.Text = 'Pregnancies';
            app.PregnanciesSlider = uislider(app.DiabetesTab);
            app.PregnanciesSlider.Limits = [0 20];
            app.PregnanciesSlider.MajorTicks = [0 5 10 15 20];
            app.PregnanciesSlider.MajorTickLabels = {'0', '5', '10', '15', '20'};
            app.PregnanciesSlider.ValueChangedFcn = createCallbackFcn(app, @PregnanciesSliderValueChanged, true);
            app.PregnanciesSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.DPFSliderLabel = uilabel(app.DiabetesTab);
            app.DPFSliderLabel.Tooltip = {'Diabetes Pedigree Function (scores likelihood of diabetes based on family)'};
            app.DPFSliderLabel.Text = 'DPF';
            app.DPFSlider = uislider(app.DiabetesTab);
            app.DPFSlider.Limits = [0 3];
            app.DPFSlider.ValueChangedFcn = createCallbackFcn(app, @DPFSliderValueChanged, true);
            app.EditField_5 = uieditfield(app.DiabetesTab, 'text');
            app.EditField_5.Value = '🔍  For further information click here  >>>  ';
            app.Hyperlink2 = uihyperlink(app.DiabetesTab);
            app.Hyperlink2.URL = 'https://www.ddg.info';
            app.Hyperlink2.Text = 'Diabetes';
            app.ImagePregnancies = uiimage(app.DiabetesTab);
            app.ImagePregnancies.ImageSource = fullfile(pathToMLAPP, 'Pregnancies.png');
            app.DIAGNOSISButton_Diabetes = uibutton(app.DiabetesTab, 'push');
            app.DIAGNOSISButton_Diabetes.ButtonPushedFcn = createCallbackFcn(app, @DIAGNOSISButton_DiabetesPushed, true);
            app.DIAGNOSISButton_Diabetes.Text = 'DIAGNOSIS';
            app.SelectionLabel3 = uilabel(app.DiabetesTab);
            app.SelectionLabel3.Text = '► Please enter the values below:';
            app.HoverCursorLabelDiabetes = uilabel(app.DiabetesTab);
            app.HoverCursorLabelDiabetes.Text = 'Hover cursor over symptoms for better definition';
            app.HomeButtonDiabetes = uibutton(app.DiabetesTab, 'push');
            app.HomeButtonDiabetes.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonDiabetesPushed, true);
            app.HomeButtonDiabetes.Icon = fullfile(pathToMLAPP, 'Bildschirmfoto 2023-06-27 um 12.51.10.png');
            app.HomeButtonDiabetes.Text = '';
            app.PredictionDiabetes = uilabel(app.DiabetesTab);
            app.PredictionDiabetes.Visible = 'off';
            app.PredictionDiabetes.Text = '';
            app.PreventionLabel = uilabel(app.DiabetesTab);
            app.PreventionLabel.Text = 'Prevention';
            app.ImageFood = uiimage(app.DiabetesTab);
            app.ImageFood.ImageSource = fullfile(pathToMLAPP, 'Diabetes Prevention Food.png');
            app.ImagehealthyWeight = uiimage(app.DiabetesTab);
            app.ImagehealthyWeight.ImageSource = fullfile(pathToMLAPP, 'Diabetes Prevention Weight.png');
            app.Sports = uiimage(app.DiabetesTab);
            app.Sports.ImageSource = fullfile(pathToMLAPP, 'Diabetes Prevention Sport.png');
            app.TreatmentLabel = uilabel(app.DiabetesTab);
            app.TreatmentLabel.Text = 'Treatment';
            app.Treatment1 = uiimage(app.DiabetesTab);
            app.Treatment1.ImageSource = fullfile(pathToMLAPP, 'Diabetes Treatment Messgerät.png');
            app.Treatment2 = uiimage(app.DiabetesTab);
            app.Treatment2.ImageSource = fullfile(pathToMLAPP, 'Diabetes Treatment Messgerät2.png');
            app.Treatment3 = uiimage(app.DiabetesTab);
            app.Treatment3.ImageSource = fullfile(pathToMLAPP, 'Diabetes Treatment Insulin.png');
            app.PreventionLabel1 = uilabel(app.DiabetesTab);
            app.PreventionLabel1.Tooltip = {'Eat healthy!'};
            app.PreventionLabel1.Text = '';
            app.PreventionLabel2 = uilabel(app.DiabetesTab);
            app.PreventionLabel2.Tooltip = {'Avoid overweight!'};
            app.PreventionLabel2.Text = '';
            app.PreventionLabel3 = uilabel(app.DiabetesTab);
            app.PreventionLabel3.Tooltip = {'Do sport!'};
            app.PreventionLabel3.Text = '';
            app.TreatmentLabel1 = uilabel(app.DiabetesTab);
            app.TreatmentLabel1.Tooltip = {'Blood glucose meter'};
            app.TreatmentLabel1.Text = '';
            app.TreatmentLabel2 = uilabel(app.DiabetesTab);
            app.TreatmentLabel2.Tooltip = {'Insulin pump'};
            app.TreatmentLabel2.Text = '';
            app.TreatmentLabel3 = uilabel(app.DiabetesTab);
            app.TreatmentLabel3.Tooltip = {'Insulin injection'};
            app.TreatmentLabel3.Text = '';
            app.HeartcomplicationsTab = uitab(app.TabGroup);
            app.HeartcomplicationsTab.Title = 'Heartcomplications';
            app.ExactButton = uibutton(app.HeartcomplicationsTab, 'push');
            app.ExactButton.ButtonPushedFcn = createCallbackFcn(app, @ExactButtonPushed, true);
            app.ExactButton.Icon = fullfile(pathToMLAPP, 'HerzExaktBild.png');
            app.ExactButton.Text = 'Exact';
            app.GeneralButton = uibutton(app.HeartcomplicationsTab, 'push');
            app.GeneralButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralButtonPushed, true);
            app.GeneralButton.Icon = fullfile(pathToMLAPP, 'HerzGeneralBild.png');
            app.GeneralButton.Text = 'General';
            app.QuestionLabel = uitextarea(app.HeartcomplicationsTab);
            app.QuestionLabel.Value = {'Do you have exact measurements or do you just want to test for heart diseases in general?'};
            app.InfoButton_Heart = uibutton(app.HeartcomplicationsTab, 'state');
            app.InfoButton_Heart.ValueChangedFcn = createCallbackFcn(app, @InfoButton_HeartValueChanged, true);
            app.InfoButton_Heart.Icon = fullfile(pathToMLAPP, 'Teamprojekt Infobutton.png');
            app.InfoButton_Heart.Text = '';
            app.InfoHTextAreaLabel = uilabel(app.HeartcomplicationsTab);
            app.InfoHTextAreaLabel.Visible = 'off';
            app.InfoHTextAreaLabel.Text = 'InfoH';
            app.Heart1TextArea = uitextarea(app.HeartcomplicationsTab);
            app.Heart1TextArea.Visible = 'off';
            app.Heart1TextArea.Value = {'Heart disease describes a range of conditions that affect the heart. Heart diseases include:'; ''; '- Blood vessel disease (such as coronary artery disease)'; '- Irregular heartbeats (arrhythmias)'; '- Heart problems you''re born with (congenital heart defects)'; '- Disease of the heart muscle'; '- Heart valve disease'};
            app.showInfo_2 = uilabel(app.HeartcomplicationsTab);
            app.showInfo_2.Text = 'For more information press the button!';
            app.HomeButtonHeart = uibutton(app.HeartcomplicationsTab, 'push');
            app.HomeButtonHeart.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonHeartPushed, true);
            app.HomeButtonHeart.Icon = fullfile(pathToMLAPP, 'Bildschirmfoto 2023-06-27 um 12.51.10.png');
            app.HomeButtonHeart.Text = '';
            app.HeartSymptomsImage = uiimage(app.HeartcomplicationsTab);
            app.HeartSymptomsImage.ImageSource = fullfile(pathToMLAPP, 'HeartSymptoms.png');
            app.LightheadednessLabel = uilabel(app.HeartcomplicationsTab);
            app.LightheadednessLabel.Text = 'Lightheadedness';
            app.ShortnessofBreathLabel = uilabel(app.HeartcomplicationsTab);
            app.ShortnessofBreathLabel.Text = 'Shortness of Breath';
            app.UpperBodyPainLabel = uilabel(app.HeartcomplicationsTab);
            app.UpperBodyPainLabel.Text = 'Upper Body Pain';
            app.ChestDiscomfortLabel = uilabel(app.HeartcomplicationsTab);
            app.ChestDiscomfortLabel.Text = 'Chest Discomfort';
            app.ColdSweatingLabel = uilabel(app.HeartcomplicationsTab);
            app.ColdSweatingLabel.Text = 'Cold Sweating';
            app.FatigueLabel = uilabel(app.HeartcomplicationsTab);
            app.FatigueLabel.Text = 'Fatigue';
            app.InsomniaLabel = uilabel(app.HeartcomplicationsTab);
            app.InsomniaLabel.Text = 'Insomnia';
            app.FlulikeSymptomsLabel = uilabel(app.HeartcomplicationsTab);
            app.FlulikeSymptomsLabel.Text = 'Flu-like Symptoms';
            app.HeartburnIndigestionLabel = uilabel(app.HeartcomplicationsTab);
            app.HeartburnIndigestionLabel.Text = 'Heartburn/Indigestion';
            app.NauseaVomitLabel = uilabel(app.HeartcomplicationsTab);
            app.NauseaVomitLabel.Text = 'Nausea/Vomit';
            app.HeartAttackSymptomsLabel = uilabel(app.HeartcomplicationsTab);
            app.HeartAttackSymptomsLabel.Text = 'Heart Attack Symptoms';
            app.FemaleLabel = uilabel(app.HeartcomplicationsTab);
            app.FemaleLabel.Text = 'Female';
            app.vsLabel = uilabel(app.HeartcomplicationsTab);
            app.vsLabel.Text = 'vs.';
            app.MaleLabel = uilabel(app.HeartcomplicationsTab);
            app.MaleLabel.Text = 'Male';
            app.ExactMeasurementsTab = uitab(app.TabGroup);
            app.ExactMeasurementsTab.Title = 'ExactMeasurements';
            app.FBSButtonGroup = uibuttongroup(app.ExactMeasurementsTab);
            app.FBSButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FBSButtonGroupSelectionChanged, true);
            app.FBSButtonGroup.Title = 'FBS';
            app.FBSnoentryButton = uiradiobutton(app.FBSButtonGroup);
            app.FBSnoentryButton.Text = 'no entry';
            app.FBSnoentryButton.Value = true;
            app.FBSTrueButton = uiradiobutton(app.FBSButtonGroup);
            app.FBSTrueButton.Text = 'True';
            app.FBSLabel = uilabel(app.FBSButtonGroup);
            app.FBSLabel.Tooltip = {'fasting blood sugar > 120 mg/dl'};
            app.FBSLabel.Text = '';
            app.FBSFalseButton = uiradiobutton(app.FBSButtonGroup);
            app.FBSFalseButton.Text = 'False';
            app.ExangButtonGroup = uibuttongroup(app.ExactMeasurementsTab);
            app.ExangButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExangButtonGroupSelectionChanged, true);
            app.ExangButtonGroup.Title = 'Exang';
            app.ExangnoentryButton = uiradiobutton(app.ExangButtonGroup);
            app.ExangnoentryButton.Text = 'no entry';
            app.ExangnoentryButton.Value = true;
            app.ExangTrueButton = uiradiobutton(app.ExangButtonGroup);
            app.ExangTrueButton.Text = 'True';
            app.ExangLabel = uilabel(app.ExangButtonGroup);
            app.ExangLabel.Tooltip = {'exercise induced angina '};
            app.ExangLabel.Text = '';
            app.ExangFalseButton = uiradiobutton(app.ExangButtonGroup);
            app.ExangFalseButton.Text = 'False';
            app.DIAGNOSISButton_HeartEx = uibutton(app.ExactMeasurementsTab, 'push');
            app.DIAGNOSISButton_HeartEx.ButtonPushedFcn = createCallbackFcn(app, @DIAGNOSISButton_HeartExPushed, true);
            app.DIAGNOSISButton_HeartEx.Text = 'DIAGNOSIS';
            app.InfoButton_HeartEx = uibutton(app.ExactMeasurementsTab, 'state');
            app.InfoButton_HeartEx.ValueChangedFcn = createCallbackFcn(app, @InfoButton_HeartExValueChanged, true);
            app.InfoButton_HeartEx.Icon = fullfile(pathToMLAPP, 'Teamprojekt Infobutton.png');
            app.InfoButton_HeartEx.Text = '';
            app.AgeEditField_2Label = uilabel(app.ExactMeasurementsTab);
            app.AgeEditField_2Label.Text = 'Age ';
            app.HeartExAgeEditField = uieditfield(app.ExactMeasurementsTab, 'numeric');
            app.HeartExAgeEditField.Limits = [0 105];
            app.HeartExAgeEditField.ValueChangedFcn = createCallbackFcn(app, @HeartExAgeEditFieldValueChanged, true);
            app.TrestbpsEditFieldLabel = uilabel(app.ExactMeasurementsTab);
            app.TrestbpsEditFieldLabel.Tooltip = {'resting blood pressure'; 'put values btw. 75-205 or -1 if you don''t know'};
            app.TrestbpsEditFieldLabel.Text = 'Trestbps';
            app.TrestbpsEditField = uieditfield(app.ExactMeasurementsTab, 'numeric');
            app.TrestbpsEditField.Limits = [-1 205];
            app.TrestbpsEditField.ValueChangedFcn = createCallbackFcn(app, @TrestbpsEditFieldValueChanged, true);
            app.TrestbpsEditField.Tooltip = {''};
            app.TrestbpsEditField.Value = -1;
            app.CholEditFieldLabel = uilabel(app.ExactMeasurementsTab);
            app.CholEditFieldLabel.Tooltip = {'serum cholesterol in mg/dl'; 'put values btw. 80-610 or -1 if you don''t know'};
            app.CholEditFieldLabel.Text = 'Chol';
            app.CholEditField = uieditfield(app.ExactMeasurementsTab, 'numeric');
            app.CholEditField.Limits = [-1 610];
            app.CholEditField.ValueChangedFcn = createCallbackFcn(app, @CholEditFieldValueChanged, true);
            app.CholEditField.Value = -1;
            app.thalchEditFieldLabel = uilabel(app.ExactMeasurementsTab);
            app.thalchEditFieldLabel.Tooltip = {'maximum heart rate achieved'; 'put a value btw. 55-210 or -1 if you don''t know'};
            app.thalchEditFieldLabel.Text = 'thalch';
            app.thalchEditField = uieditfield(app.ExactMeasurementsTab, 'numeric');
            app.thalchEditField.Limits = [-1 210];
            app.thalchEditField.ValueChangedFcn = createCallbackFcn(app, @thalchEditFieldValueChanged, true);
            app.thalchEditField.Value = -1;
            app.caSpinnerLabel = uilabel(app.ExactMeasurementsTab);
            app.caSpinnerLabel.Tooltip = {'number of major vessels (0-3) colored by fluoroscopy'; 'put -1 if you don''t know'};
            app.caSpinnerLabel.Text = 'ca';
            app.caSpinner = uispinner(app.ExactMeasurementsTab);
            app.caSpinner.Limits = [-1 3];
            app.caSpinner.ValueChangedFcn = createCallbackFcn(app, @caSpinnerValueChanged, true);
            app.caSpinner.Value = -1;
            app.SexDropDown_2Label = uilabel(app.ExactMeasurementsTab);
            app.SexDropDown_2Label.Text = 'Sex';
            app.HeartExSexDropDown = uidropdown(app.ExactMeasurementsTab);
            app.HeartExSexDropDown.Items = {'no entry', 'Male', 'Female'};
            app.HeartExSexDropDown.ValueChangedFcn = createCallbackFcn(app, @HeartExSexDropDownValueChanged, true);
            app.HeartExSexDropDown.Value = 'no entry';
            app.cpDropDownLabel = uilabel(app.ExactMeasurementsTab);
            app.cpDropDownLabel.Tooltip = {'chest pain type'};
            app.cpDropDownLabel.Text = 'cp';
            app.cpDropDown = uidropdown(app.ExactMeasurementsTab);
            app.cpDropDown.Items = {'no entry', 'typical angina', 'atypical angina', 'non-anginal', 'asymptomatic'};
            app.cpDropDown.ValueChangedFcn = createCallbackFcn(app, @cpDropDownValueChanged, true);
            app.cpDropDown.Value = 'no entry';
            app.restecgDropDownLabel = uilabel(app.ExactMeasurementsTab);
            app.restecgDropDownLabel.Tooltip = {'resting electrocardiographic results'};
            app.restecgDropDownLabel.Text = 'restecg';
            app.restecgDropDown = uidropdown(app.ExactMeasurementsTab);
            app.restecgDropDown.Items = {'no entry', 'normal', 'lv hypertrophy', 'stt abnormality'};
            app.restecgDropDown.ValueChangedFcn = createCallbackFcn(app, @restecgDropDownValueChanged, true);
            app.restecgDropDown.Value = 'no entry';
            app.OldpeakEditFieldLabel = uilabel(app.ExactMeasurementsTab);
            app.OldpeakEditFieldLabel.Tooltip = {'ST depression induced by exercise relative to rest'; 'put a value btw. 0-6.5 or -1 if you don''t know'};
            app.OldpeakEditFieldLabel.Text = 'Oldpeak';
            app.OldpeakEditField = uieditfield(app.ExactMeasurementsTab, 'numeric');
            app.OldpeakEditField.Limits = [-1 6.5];
            app.OldpeakEditField.ValueChangedFcn = createCallbackFcn(app, @OldpeakEditFieldValueChanged, true);
            app.OldpeakEditField.Value = -1;
            app.InfoTextAreaLabel = uilabel(app.ExactMeasurementsTab);
            app.InfoTextAreaLabel.Visible = 'off';
            app.InfoTextAreaLabel.Text = 'Info';
            app.Heart2TextArea = uitextarea(app.ExactMeasurementsTab);
            app.Heart2TextArea.Visible = 'off';
            app.Heart2TextArea.Value = {'Heart disease describes a range of conditions that affect the heart. Heart diseases include:'; ''; '- Blood vessel disease (such as '; '   coronary artery disease)'; '- Irregular heartbeats '; '  (arrhythmias)'; '- Heart problems you''re born '; '   with (congenital heart  '; '   defects)'; '- Disease of the heart muscle'; '- Heart valve disease'};
            app.ThalDropDownLabel = uilabel(app.ExactMeasurementsTab);
            app.ThalDropDownLabel.Tooltip = {' blood disorder called thalassemia'};
            app.ThalDropDownLabel.Text = 'Thal';
            app.ThalDropDown = uidropdown(app.ExactMeasurementsTab);
            app.ThalDropDown.Items = {'no entry', 'normal', 'fixed defect', 'reversable defect'};
            app.ThalDropDown.ValueChangedFcn = createCallbackFcn(app, @ThalDropDownValueChanged, true);
            app.ThalDropDown.Value = 'no entry';
            app.slopeDropDownLabel = uilabel(app.ExactMeasurementsTab);
            app.slopeDropDownLabel.Tooltip = {'slope of the peak exercise ST segment'};
            app.slopeDropDownLabel.Text = 'slope';
            app.slopeDropDown = uidropdown(app.ExactMeasurementsTab);
            app.slopeDropDown.Items = {'no entry', 'downsloping', 'upsloping', 'flat'};
            app.slopeDropDown.ValueChangedFcn = createCallbackFcn(app, @slopeDropDownValueChanged, true);
            app.slopeDropDown.Value = 'no entry';
            app.HeartExFurtherInformation = uieditfield(app.ExactMeasurementsTab, 'text');
            app.HeartExFurtherInformation.Value = '🔍  For further information click here  >>>  ';
            app.showInfo_3 = uilabel(app.ExactMeasurementsTab);
            app.showInfo_3.Text = 'For more information press the button!';
            app.HeartExHyperlink = uihyperlink(app.ExactMeasurementsTab);
            app.HeartExHyperlink.URL = 'https://www.mayoclinic.org/diseases-conditions/heart-disease/symptoms-causes/syc-20353118';
            app.HeartExHyperlink.Text = 'Heart Disease';
            app.PressButtonLabel = uilabel(app.ExactMeasurementsTab);
            app.PressButtonLabel.Text = 'Press button for the prediction:';
            app.EnterValuesLabel = uilabel(app.ExactMeasurementsTab);
            app.EnterValuesLabel.Text = '► Please enter the values below:';
            app.HoverCursorLabel = uilabel(app.ExactMeasurementsTab);
            app.HoverCursorLabel.Text = 'Hover cursor over symptoms for better definition';
            app.HomeButtonHeartEx = uibutton(app.ExactMeasurementsTab, 'push');
            app.HomeButtonHeartEx.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonHeartExPushed, true);
            app.HomeButtonHeartEx.Icon = fullfile(pathToMLAPP, 'Bildschirmfoto 2023-06-27 um 12.51.10.png');
            app.HomeButtonHeartEx.Text = '';
            app.HeartExPredictionLabel = uilabel(app.ExactMeasurementsTab);
            app.HeartExPredictionLabel.Visible = 'off';
            app.HeartExPredictionLabel.Text = '';
            app.BackButtonHeartEx = uibutton(app.ExactMeasurementsTab, 'push');
            app.BackButtonHeartEx.ButtonPushedFcn = createCallbackFcn(app, @BackButtonHeartExPushed, true);
            app.BackButtonHeartEx.Icon = fullfile(pathToMLAPP, 'ReturnArrow2.png');
            app.BackButtonHeartEx.Text = '';
            app.ImageHeartPatient = uiimage(app.ExactMeasurementsTab);
            app.ImageHeartPatient.ImageSource = fullfile(pathToMLAPP, 'Herzpatient.png');
            app.ImageHeart5 = uiimage(app.ExactMeasurementsTab);
            app.ImageHeart5.ImageSource = fullfile(pathToMLAPP, 'heart_diseases5.png');
            app.ImageHeart4 = uiimage(app.ExactMeasurementsTab);
            app.ImageHeart4.ImageSource = fullfile(pathToMLAPP, 'heart_diseases3.png');
            app.ImageHeart3 = uiimage(app.ExactMeasurementsTab);
            app.ImageHeart3.ImageSource = fullfile(pathToMLAPP, 'heart_diseases2.png');
            app.ImageHeart2 = uiimage(app.ExactMeasurementsTab);
            app.ImageHeart2.ImageSource = fullfile(pathToMLAPP, 'heart_diseases1.png');
            app.ImageHeart1 = uiimage(app.ExactMeasurementsTab);
            app.ImageHeart1.ImageSource = fullfile(pathToMLAPP, 'heart_diseases4.png');
            app.HeartLabelPic5 = uilabel(app.ExactMeasurementsTab);
            app.HeartLabelPic5.Text = 'Heart Failure';
            app.HeartLabelPic4 = uilabel(app.ExactMeasurementsTab);
            app.HeartLabelPic4.Text = 'Heart Infections';
            app.HeartLabelPic3 = uilabel(app.ExactMeasurementsTab);
            app.HeartLabelPic3.Text = 'Heart Valve Disease';
            app.HeartLabelPic2 = uilabel(app.ExactMeasurementsTab);
            app.HeartLabelPic2.Text = {'Atherosclerotic '; 'Disease'};
            app.HeartLabelPic1 = uilabel(app.ExactMeasurementsTab);
            app.HeartLabelPic1.Text = 'Carciac Arrhythmias';
            app.TypesLabel = uilabel(app.ExactMeasurementsTab);
            app.TypesLabel.Tooltip = {'Types of Heart Disease'};
            app.TypesLabel.Text = '';
            app.GeneralMeasurementsTab = uitab(app.TabGroup);
            app.GeneralMeasurementsTab.Title = 'GeneralMeasurements';
            app.AlcoholconsumptionCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.AlcoholconsumptionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlcoholconsumptionCheckBoxValueChanged, true);
            app.AlcoholconsumptionCheckBox.Text = 'Heavy alcohol consumption';
            app.SmokingCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.SmokingCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmokingCheckBoxValueChanged, true);
            app.SmokingCheckBox.Text = 'Smoked at least 100 cigarettes in entire life';
            app.BloodcholesterolCheckupCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.BloodcholesterolCheckupCheckBox.ValueChangedFcn = createCallbackFcn(app, @BloodcholesterolCheckupCheckBoxValueChanged, true);
            app.BloodcholesterolCheckupCheckBox.Text = 'High blood cholesterol in last 5 years';
            app.HighbloodpressureCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.HighbloodpressureCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighbloodpressureCheckBoxValueChanged, true);
            app.HighbloodpressureCheckBox.Text = 'High blood pressure';
            app.StrokeCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.StrokeCheckBox.ValueChangedFcn = createCallbackFcn(app, @StrokeCheckBoxValueChanged, true);
            app.StrokeCheckBox.Text = 'Had a stroke';
            app.ExercisedCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.ExercisedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExercisedCheckBoxValueChanged, true);
            app.ExercisedCheckBox.Text = 'Has exercised in the last 30 days';
            app.BloodcholersterolCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.BloodcholersterolCheckBox.ValueChangedFcn = createCallbackFcn(app, @BloodcholersterolCheckBoxValueChanged, true);
            app.BloodcholersterolCheckBox.Text = 'Ever had high blood cholersterol';
            app.VegetableCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.VegetableCheckBox.ValueChangedFcn = createCallbackFcn(app, @VegetableCheckBoxValueChanged, true);
            app.VegetableCheckBox.Text = 'Eats at least one vegetable a day';
            app.FruitCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.FruitCheckBox.ValueChangedFcn = createCallbackFcn(app, @FruitCheckBoxValueChanged, true);
            app.FruitCheckBox.Text = 'Eats at least one fruit a day';
            app.DifficultyWalkingCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.DifficultyWalkingCheckBox.ValueChangedFcn = createCallbackFcn(app, @DifficultyWalkingCheckBoxValueChanged, true);
            app.DifficultyWalkingCheckBox.Text = 'Serious difficulty walking or climbing stairs';
            app.DIAGNOSISButton_HeartGen = uibutton(app.GeneralMeasurementsTab, 'push');
            app.DIAGNOSISButton_HeartGen.ButtonPushedFcn = createCallbackFcn(app, @DIAGNOSISButton_HeartGenPushed, true);
            app.DIAGNOSISButton_HeartGen.Text = 'DIAGNOSIS';
            app.BMIroundedtointegerEditFieldLabel = uilabel(app.GeneralMeasurementsTab);
            app.BMIroundedtointegerEditFieldLabel.Text = 'BMI rounded to integer';
            app.BMIEditField = uieditfield(app.GeneralMeasurementsTab, 'numeric');
            app.BMIEditField.Limits = [0 100];
            app.BMIEditField.ValueChangedFcn = createCallbackFcn(app, @BMIEditFieldValueChanged, true);
            app.AgeEditFieldLabel = uilabel(app.GeneralMeasurementsTab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.GeneralMeasurementsTab, 'numeric');
            app.AgeEditField.Limits = [0 120];
            app.AgeEditField.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.DiabetesDropDownLabel = uilabel(app.GeneralMeasurementsTab);
            app.DiabetesDropDownLabel.Text = 'Diabetes';
            app.DiabetesDropDown = uidropdown(app.GeneralMeasurementsTab);
            app.DiabetesDropDown.Items = {'Yes (outside of pregnancy)', 'No', 'Borderline'};
            app.DiabetesDropDown.ValueChangedFcn = createCallbackFcn(app, @DiabetesDropDownValueChanged, true);
            app.DiabetesDropDown.Value = 'Yes (outside of pregnancy)';
            app.GeneralHealthDropDownLabel = uilabel(app.GeneralMeasurementsTab);
            app.GeneralHealthDropDownLabel.Text = 'General Health';
            app.GeneralHealthDropDown = uidropdown(app.GeneralMeasurementsTab);
            app.GeneralHealthDropDown.Items = {'Excellent', 'Very good', 'Good', 'Fair', 'Poor'};
            app.GeneralHealthDropDown.ValueChangedFcn = createCallbackFcn(app, @GeneralHealthDropDownValueChanged, true);
            app.GeneralHealthDropDown.Value = 'Excellent';
            app.HighestcompletedGradeDropDownLabel = uilabel(app.GeneralMeasurementsTab);
            app.HighestcompletedGradeDropDownLabel.Text = 'Highest completed Grade';
            app.GradeDropDown = uidropdown(app.GeneralMeasurementsTab);
            app.GradeDropDown.Items = {'No school or only kindergarten', 'Elementary', 'Grades 9 through 11', 'Grade 12 or GED', 'College 1-3 years', 'College for at least 4 years'};
            app.GradeDropDown.ValueChangedFcn = createCallbackFcn(app, @GradeDropDownValueChanged, true);
            app.GradeDropDown.Value = 'No school or only kindergarten';
            app.AnnualhouseholdincomeallsourcesLabel = uilabel(app.GeneralMeasurementsTab);
            app.AnnualhouseholdincomeallsourcesLabel.Text = 'Annual household income (all sources)';
            app.IncomeEditField = uieditfield(app.GeneralMeasurementsTab, 'numeric');
            app.IncomeEditField.Limits = [0 Inf];
            app.IncomeEditField.ValueChangedFcn = createCallbackFcn(app, @IncomeEditFieldValueChanged, true);
            app.SexDropDownLabel = uilabel(app.GeneralMeasurementsTab);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.GeneralMeasurementsTab);
            app.SexDropDown.Items = {'Male', 'Female'};
            app.SexDropDown.ValueChangedFcn = createCallbackFcn(app, @SexDropDownValueChanged, true);
            app.SexDropDown.Value = 'Male';
            app.Dayswithbadmentalhealthinthelast30daysEditFieldLabel = uilabel(app.GeneralMeasurementsTab);
            app.Dayswithbadmentalhealthinthelast30daysEditFieldLabel.Text = 'Days with bad mental health in the last 30 days';
            app.MentalhealthEditField = uieditfield(app.GeneralMeasurementsTab, 'numeric');
            app.MentalhealthEditField.Limits = [0 30];
            app.MentalhealthEditField.ValueChangedFcn = createCallbackFcn(app, @MentalhealthEditFieldValueChanged, true);
            app.Dayswithbadphysicalhealthinthelast30daysEditFieldLabel = uilabel(app.GeneralMeasurementsTab);
            app.Dayswithbadphysicalhealthinthelast30daysEditFieldLabel.Text = 'Days with bad physical health in the last 30 days';
            app.PhysicalhealthEditField = uieditfield(app.GeneralMeasurementsTab, 'numeric');
            app.PhysicalhealthEditField.Limits = [0 30];
            app.PhysicalhealthEditField.ValueChangedFcn = createCallbackFcn(app, @PhysicalhealthEditFieldValueChanged, true);
            app.DoctorssvisitiCostsCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.DoctorssvisitiCostsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoctorssvisitiCostsCheckBoxValueChanged, true);
            app.DoctorssvisitiCostsCheckBox.Text = 'Costs too high for doctors''s visit (in last 12 months)';
            app.HealthcarecoverageCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.HealthcarecoverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @HealthcarecoverageCheckBoxValueChanged, true);
            app.HealthcarecoverageCheckBox.Text = 'Has any kind of healthcare coverage';
            app.EditField_3 = uieditfield(app.GeneralMeasurementsTab, 'text');
            app.EditField_3.Value = '🔍  For further information click here  >>>  ';
            app.InfoPredictionLabel_5 = uilabel(app.GeneralMeasurementsTab);
            app.InfoPredictionLabel_5.Text = 'Press button for the prediction:';
            app.Hyperlink4 = uihyperlink(app.GeneralMeasurementsTab);
            app.Hyperlink4.URL = 'https://www.mayoclinic.org/diseases-conditions/heart-disease/symptoms-causes/syc-20353118';
            app.Hyperlink4.Text = 'Heart Disease';
            app.Prediction_3 = uilabel(app.GeneralMeasurementsTab);
            app.Prediction_3.Visible = 'off';
            app.Prediction_3.Text = '';
            app.SelectionLabel4 = uilabel(app.GeneralMeasurementsTab);
            app.SelectionLabel4.Text = '► Please enter the values below:';
            app.HomeButtonHeartGen = uibutton(app.GeneralMeasurementsTab, 'push');
            app.HomeButtonHeartGen.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonHeartGenPushed, true);
            app.HomeButtonHeartGen.Icon = fullfile(pathToMLAPP, 'Bildschirmfoto 2023-06-27 um 12.51.10.png');
            app.HomeButtonHeartGen.Text = '';
            app.InfoButton_HeartGen = uibutton(app.GeneralMeasurementsTab, 'state');
            app.InfoButton_HeartGen.ValueChangedFcn = createCallbackFcn(app, @InfoButton_HeartGenValueChanged, true);
            app.InfoButton_HeartGen.Icon = fullfile(pathToMLAPP, 'Teamprojekt Infobutton.png');
            app.InfoButton_HeartGen.Text = '';
            app.InfoTextAreaLabel_2 = uilabel(app.GeneralMeasurementsTab);
            app.InfoTextAreaLabel_2.Visible = 'off';
            app.InfoTextAreaLabel_2.Text = 'Info';
            app.HeartGenTextArea = uitextarea(app.GeneralMeasurementsTab);
            app.HeartGenTextArea.Visible = 'off';
            app.HeartGenTextArea.Value = {'Heart disease describes a range of conditions that affect the heart. Heart diseases include:'; ''; '- Blood vessel disease (such as '; '   coronary artery disease)'; '- Irregular heartbeats '; '  (arrhythmias)'; '- Heart problems you''re born '; '   with (congenital heart  '; '   defects)'; '- Disease of the heart muscle'; '- Heart valve disease'};
            app.ImageSex = uiimage(app.GeneralMeasurementsTab);
            app.ImageSex.ImageSource = fullfile(pathToMLAPP, 'Geschlecht.png');
            app.ImageDiabetes = uiimage(app.GeneralMeasurementsTab);
            app.ImageDiabetes.ImageSource = fullfile(pathToMLAPP, 'Insulin.png');
            app.ImageGrade = uiimage(app.GeneralMeasurementsTab);
            app.ImageGrade.ImageSource = fullfile(pathToMLAPP, 'Grades.png');
            app.ImageHealth = uiimage(app.GeneralMeasurementsTab);
            app.ImageHealth.ImageSource = fullfile(pathToMLAPP, 'Health.png');
            app.BackButtonHeartGen = uibutton(app.GeneralMeasurementsTab, 'push');
            app.BackButtonHeartGen.ButtonPushedFcn = createCallbackFcn(app, @BackButtonHeartGenPushed, true);
            app.BackButtonHeartGen.Icon = fullfile(pathToMLAPP, 'ReturnArrow2.png');
            app.BackButtonHeartGen.Text = '';
            app.LumpyskinTab = uitab(app.TabGroup);
            app.LumpyskinTab.Title = 'Lumpyskin';
            app.DIAGNOSISButton_LumpySkin = uibutton(app.LumpyskinTab, 'push');
            app.DIAGNOSISButton_LumpySkin.ButtonPushedFcn = createCallbackFcn(app, @DIAGNOSISButton_LumpySkinPushed, true);
            app.DIAGNOSISButton_LumpySkin.Text = 'DIAGNOSIS';
            app.LumpySkinTextArea = uitextarea(app.LumpyskinTab);
            app.LumpySkinTextArea.Visible = 'off';
            app.LumpySkinTextArea.Value = {'"Lumpy skin disease (LSD) is a highly contagious viral disease of cattle and buffalo that causes relatively low mortality. It does not affect humans. The disease can result in animal welfare issues and significant production losses."'};
            app.EditField_2 = uieditfield(app.LumpyskinTab, 'text');
            app.EditField_2.Value = '🔍  For further information click here  >>>  ';
            app.Hyperlink_3 = uihyperlink(app.LumpyskinTab);
            app.Hyperlink_3.URL = 'https://www.agriculture.gov.au/biosecurity-trade/pests-diseases-weeds/animal/lumpy-skin-disease#:~:text=Lumpy%20skin%20disease%20(LSD)%20is,issues%20and%20significant%20production%20losses.';
            app.Hyperlink_3.Text = 'Lumpy skin disease';
            app.InfoButton_LumpySkin = uibutton(app.LumpyskinTab, 'state');
            app.InfoButton_LumpySkin.ValueChangedFcn = createCallbackFcn(app, @InfoButton_LumpySkinValueChanged, true);
            app.InfoButton_LumpySkin.Icon = fullfile(pathToMLAPP, 'Teamprojekt Infobutton.png');
            app.InfoButton_LumpySkin.Text = '';
            app.Image5 = uiimage(app.LumpyskinTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'world_map_mariella_teamproject.png');
            app.MoreInformationLabel_2 = uilabel(app.LumpyskinTab);
            app.MoreInformationLabel_2.Text = 'For more information press the button!';
            app.Label_16 = uilabel(app.LumpyskinTab);
            app.Label_16.Tooltip = {'https://onlinelibrary.wiley.com'};
            app.Label_16.Text = '';
            app.InfoPredictionLabel_2 = uilabel(app.LumpyskinTab);
            app.InfoPredictionLabel_2.Text = 'Press button for the prediction:';
            app.Prediction_2 = uilabel(app.LumpyskinTab);
            app.Prediction_2.Visible = 'off';
            app.Prediction_2.Text = '';
            app.xcoordinateEditFieldLabel = uilabel(app.LumpyskinTab);
            app.xcoordinateEditFieldLabel.Tooltip = {'value from -180 to 180'};
            app.xcoordinateEditFieldLabel.Text = 'x-coordinate';
            app.xcoordinateEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.xcoordinateEditField.Limits = [-180 180];
            app.xcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.ycoordinateEditFieldLabel = uilabel(app.LumpyskinTab);
            app.ycoordinateEditFieldLabel.Tooltip = {'value from -28.8 to 81.8'};
            app.ycoordinateEditFieldLabel.Text = 'y-coordinate';
            app.ycoordinateEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.ycoordinateEditField.Limits = [-28.8 81.8];
            app.ycoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @ycoordinateEditFieldValueChanged, true);
            app.cldEditFieldLabel = uilabel(app.LumpyskinTab);
            app.cldEditFieldLabel.Tooltip = {'cloud cover, value from 0 to 98.7'};
            app.cldEditFieldLabel.Text = 'cld';
            app.cldEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.cldEditField.Limits = [0 98.7];
            app.cldEditField.ValueChangedFcn = createCallbackFcn(app, @cldEditFieldValueChanged, true);
            app.dtrEditFieldLabel = uilabel(app.LumpyskinTab);
            app.dtrEditFieldLabel.Tooltip = {'diurnal temperature range, value from 0 to 20.6'};
            app.dtrEditFieldLabel.Text = 'dtr';
            app.dtrEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.dtrEditField.Limits = [0 20.6];
            app.dtrEditField.ValueChangedFcn = createCallbackFcn(app, @dtrEditFieldValueChanged, true);
            app.frsEditFieldLabel = uilabel(app.LumpyskinTab);
            app.frsEditFieldLabel.Tooltip = {'frost day frequence, value from 0 to 31'};
            app.frsEditFieldLabel.Text = 'frs';
            app.frsEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.frsEditField.Limits = [0 31];
            app.frsEditField.ValueChangedFcn = createCallbackFcn(app, @frsEditFieldValueChanged, true);
            app.petEditFieldLabel = uilabel(app.LumpyskinTab);
            app.petEditFieldLabel.Tooltip = {'Potential Evapo-transpiration, value from 0 to 342'};
            app.petEditFieldLabel.Text = 'pet';
            app.petEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.petEditField.Limits = [0 342];
            app.petEditField.ValueChangedFcn = createCallbackFcn(app, @petEditFieldValueChanged, true);
            app.vrapEditFieldLabel = uilabel(app.LumpyskinTab);
            app.vrapEditFieldLabel.Tooltip = {'vapour pressure, value from 0 to 28.6'};
            app.vrapEditFieldLabel.Text = 'vrap';
            app.vrapEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.vrapEditField.Limits = [0 28.6];
            app.vrapEditField.ValueChangedFcn = createCallbackFcn(app, @vrapEditFieldValueChanged, true);
            app.wetEditFieldLabel = uilabel(app.LumpyskinTab);
            app.wetEditFieldLabel.Tooltip = {'wet day frequence, value from 0 to 30.9'};
            app.wetEditFieldLabel.Text = 'wet';
            app.wetEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.wetEditField.Limits = [0 30.9];
            app.wetEditField.ValueChangedFcn = createCallbackFcn(app, @wetEditFieldValueChanged, true);
            app.elevationEditFieldLabel = uilabel(app.LumpyskinTab);
            app.elevationEditFieldLabel.Tooltip = {'value from 66 to 294 '};
            app.elevationEditFieldLabel.Text = 'elevation';
            app.elevationEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.elevationEditField.Limits = [0 294];
            app.elevationEditField.ValueChangedFcn = createCallbackFcn(app, @elevationEditFieldValueChanged, true);
            app.dominantlandEditFieldLabel = uilabel(app.LumpyskinTab);
            app.dominantlandEditFieldLabel.Tooltip = {'dominant land cover, value from 0 to 11'};
            app.dominantlandEditFieldLabel.Text = 'dominant land';
            app.dominantlandEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.dominantlandEditField.Limits = [0 11];
            app.dominantlandEditField.ValueChangedFcn = createCallbackFcn(app, @dominantlandEditFieldValueChanged, true);
            app.X5_Ct_2010_DaEditFieldLabel = uilabel(app.LumpyskinTab);
            app.X5_Ct_2010_DaEditFieldLabel.Tooltip = {'value from 0 to 167k'};
            app.X5_Ct_2010_DaEditFieldLabel.Text = 'X5_Ct_2010_Da';
            app.X5_Ct_2010_DaEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.X5_Ct_2010_DaEditField.Limits = [0 167000];
            app.X5_Ct_2010_DaEditField.ValueChangedFcn = createCallbackFcn(app, @X5_Ct_2010_DaEditFieldValueChanged, true);
            app.X5_Bf_2010_DaEditFieldLabel = uilabel(app.LumpyskinTab);
            app.X5_Bf_2010_DaEditFieldLabel.Tooltip = {'value from 0 to 57k'};
            app.X5_Bf_2010_DaEditFieldLabel.Text = 'X5_Bf_2010_Da';
            app.X5_Bf_2010_DaEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.X5_Bf_2010_DaEditField.Limits = [0 57000];
            app.X5_Bf_2010_DaEditField.ValueChangedFcn = createCallbackFcn(app, @X5_Bf_2010_DaEditFieldValueChanged, true);
            app.preEditFieldLabel = uilabel(app.LumpyskinTab);
            app.preEditFieldLabel.Tooltip = {'precipitation, value from 0 to 7.5'};
            app.preEditFieldLabel.Text = 'pre';
            app.preEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.preEditField.Limits = [0 7.5];
            app.preEditField.ValueChangedFcn = createCallbackFcn(app, @preEditFieldValueChanged, true);
            app.tmnEditFieldLabel = uilabel(app.LumpyskinTab);
            app.tmnEditFieldLabel.Tooltip = {'monthly average daily minimum temperature, value from -52.1 to 23.9'};
            app.tmnEditFieldLabel.Text = 'tmn';
            app.tmnEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.tmnEditField.Limits = [-52.1 23.9];
            app.tmnEditField.ValueChangedFcn = createCallbackFcn(app, @tmnEditFieldValueChanged, true);
            app.tmpEditFieldLabel = uilabel(app.LumpyskinTab);
            app.tmpEditFieldLabel.Tooltip = {'daily mean temperature, value from -48.1 to 28.5'};
            app.tmpEditFieldLabel.Text = 'tmp';
            app.tmpEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.tmpEditField.Limits = [-48.1 28.5];
            app.tmpEditField.ValueChangedFcn = createCallbackFcn(app, @tmpEditFieldValueChanged, true);
            app.tmxEditFieldLabel = uilabel(app.LumpyskinTab);
            app.tmxEditFieldLabel.Tooltip = {'monthly average daily maximum temperature, value from -44.2 to 36.4'};
            app.tmxEditFieldLabel.Text = 'tmx';
            app.tmxEditField = uieditfield(app.LumpyskinTab, 'numeric');
            app.tmxEditField.Limits = [-44.2 36.4];
            app.tmxEditField.ValueChangedFcn = createCallbackFcn(app, @tmxEditFieldValueChanged, true);
            app.SelectionLabel = uilabel(app.LumpyskinTab);
            app.SelectionLabel.Text = '► Please enter the values below:';
            app.InfoLabel_2 = uilabel(app.LumpyskinTab);
            app.InfoLabel_2.Text = 'Hover cursor over symptoms for better definition';
            app.HomeButtonLumpySkin = uibutton(app.LumpyskinTab, 'push');
            app.HomeButtonLumpySkin.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonLumpySkinPushed, true);
            app.HomeButtonLumpySkin.Icon = fullfile(pathToMLAPP, 'Bildschirmfoto 2023-06-27 um 12.51.10.png');
            app.HomeButtonLumpySkin.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu_fixed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end