classdef Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        PleaseSelectwhatyouwanttoexamineyourpatientforLabel  matlab.ui.control.Label
        LumpyskinButton                matlab.ui.control.Button
        HeartcomplicationsButton       matlab.ui.control.Button
        DiabetesButton                 matlab.ui.control.Button
        GeneralDiseasesButton          matlab.ui.control.Button
        GeneralDiseasesTab_2           matlab.ui.container.Tab
        DiabetesTab                    matlab.ui.container.Tab
        HeartcomplicationsTab          matlab.ui.container.Tab
        GeneralButton                  matlab.ui.control.Button
        ExactButton                    matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        ExactMeasurementsTab           matlab.ui.container.Tab
        GeneralMeasurementsTab         matlab.ui.container.Tab
        HasanykindofhealthcarecoverageCheckBox  matlab.ui.control.CheckBox
        Coststoohighfordoctorssvisitinlast12monthsCheckBox  matlab.ui.control.CheckBox
        Dayswithbadphysicalhealthinthelast30daysEditField  matlab.ui.control.NumericEditField
        Dayswithbadphysicalhealthinthelast30daysEditFieldLabel  matlab.ui.control.Label
        Dayswithbadmentalhealthinthelast30daysEditField  matlab.ui.control.NumericEditField
        Dayswithbadmentalhealthinthelast30daysEditFieldLabel  matlab.ui.control.Label
        SexDropDown                    matlab.ui.control.DropDown
        SexDropDownLabel               matlab.ui.control.Label
        AnnualhouseholdincomeallsourcesEditField  matlab.ui.control.NumericEditField
        AnnualhouseholdincomeallsourcesLabel  matlab.ui.control.Label
        HighestcompletedGradeDropDown  matlab.ui.control.DropDown
        HighestcompletedGradeDropDownLabel  matlab.ui.control.Label
        GeneralHealthDropDown          matlab.ui.control.DropDown
        GeneralHealthDropDownLabel     matlab.ui.control.Label
        DiabetesDropDown               matlab.ui.control.DropDown
        DiabetesDropDownLabel          matlab.ui.control.Label
        AgeEditField                   matlab.ui.control.NumericEditField
        AgeEditFieldLabel              matlab.ui.control.Label
        BMIroundedtointegerEditField   matlab.ui.control.NumericEditField
        BMIroundedtointegerEditFieldLabel  matlab.ui.control.Label
        PleaseenterthepatientsvaluesbellowLabel  matlab.ui.control.Label
        EditField2                     matlab.ui.control.EditField
        DIAGNOSISButton                matlab.ui.control.Button
        FeedbackTextArea               matlab.ui.control.TextArea
        SeriousdifficultywalkingorclimbingstairsCheckBox  matlab.ui.control.CheckBox
        EatsatleastonefruitadayCheckBox  matlab.ui.control.CheckBox
        EatsatleastonevegetableadayCheckBox  matlab.ui.control.CheckBox
        EverhadhighbloodcholersterolCheckBox  matlab.ui.control.CheckBox
        Hasexercisedinthelast30daysCheckBox  matlab.ui.control.CheckBox
        HadastrokeCheckBox             matlab.ui.control.CheckBox
        HighbloodpressureCheckBox      matlab.ui.control.CheckBox
        Highbloodcholesterolinlast5yearsCheckBox  matlab.ui.control.CheckBox
        Smokedatleast100cigarettesinentirelifeCheckBox  matlab.ui.control.CheckBox
        HeavyalcoholconsumptionCheckBox  matlab.ui.control.CheckBox
        LumpyskinTab                   matlab.ui.container.Tab
    end

    
    properties (Access = private)
        HHIML % get ML model for HHI
        HHISY % get Symptoms from HHI
        current_feature % table to fill for HHI
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneralDiseasesButton
        function GeneralDiseasesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.GeneralDiseasesTab_2; % Open correlating Tab
        end

        % Button pushed function: DiabetesButton
        function DiabetesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DiabetesTab; % Open correlating Tab
        end

        % Button pushed function: HeartcomplicationsButton
        function HeartcomplicationsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HeartcomplicationsTab; % Open correlating Tab
        end

        % Button pushed function: LumpyskinButton
        function LumpyskinButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.LumpyskinTab; % Open correlating Tab
        end

        % Button pushed function: ExactButton
        function ExactButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ExactMeasurementsTab;
        end

        % Button pushed function: GeneralButton
        function GeneralButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.GeneralMeasurementsTab;
            load('HHIModelTreeBagger.mat','MLmodel', 'all_symptoms_hh'); %load model and symptoms
            
            app.HHIML=MLmodel; %store model in our class
            app.HHISY=all_symptoms_hh; %store symptoms in our class
            app.current_feature=zeros(1, 21); %create a new feature %size(app.HHISY, 2)-1 warum geht das nicht mehr
        end

        % Button pushed function: DIAGNOSISButton
        function DIAGNOSISButtonPushed(app, event)
            app.FeedbackTextArea.Value = predict(app.HHIML,app.current_feature); 
            % Rückgabe verschönern
        end

        % Value changed function: SexDropDown
        function SexDropDownValueChanged(app, event)
            value = app.SexDropDown.Value;
            switch value
                case 'Male'
                    % Aktion für Option 1 ausführen
                    zahl = 1;
                case 'Female'
                    % Aktion für Option 2 ausführen
                    zahl = 0;
            end
            app.current_feature(1, 18)= zahl;
        end

        % Value changed function: HighestcompletedGradeDropDown
        function HighestcompletedGradeDropDownValueChanged(app, event)
            value = app.HighestcompletedGradeDropDown.Value;
            switch value
                case 'No school or only kindergarten'
                    zahl = 1;
                case 'Elementary'
                    zahl = 2;
                case 'Grades 9 through 11'
                    zahl = 3;
                case 'Grade 12 or GED'
                    zahl = 4;
                case 'College 1-3 years'
                    zahl = 5;
                case 'College for at least 4 years'
                    zahl = 6;
            end
            app.current_feature(1, 20)= zahl;
        end

        % Value changed function: DiabetesDropDown
        function DiabetesDropDownValueChanged(app, event)
            value = app.DiabetesDropDown.Value;
            switch value
                case 'Yes (outside of pregnancy)'
                    zahl = 2;
                case 'No'
                    zahl = 0;
                case 'Borderline'
                    zahl = 1;
            end
            app.current_feature(1, 7)= zahl;
        end

        % Value changed function: GeneralHealthDropDown
        function GeneralHealthDropDownValueChanged(app, event)
            value = app.GeneralHealthDropDown.Value;
            switch value
                case 'Excellent'
                    % Aktion für Option 1 ausführen
                    zahl = 1;
                case 'Very good'
                    % Aktion für Option 2 ausführen
                    zahl = 2;
                case 'Good'
                    % Aktion für Option 3 ausführen
                    zahl = 3;
                case 'Fair'
                    zahl = 4;
                case 'Poor'
                    zahl = 5;
            end
            app.current_feature(1, 14)= zahl;
        end

        % Value changed function: AgeEditField
        function AgeEditFieldValueChanged(app, event)
            value = app.AgeEditField.Value;
            if value <= 25
                helper = 1;
            elseif value <= 29
                helper = 2;
            elseif value <= 34
                helper = 3;
            elseif value <= 39
                helper = 4;
            elseif value <= 44
                helper = 5;
            elseif value <= 49
                helper = 6;
            elseif value <= 54
                helper = 7;
            elseif value <= 59
                helper = 8;
            elseif value <= 64
                helper = 9;
            elseif value <= 69
                helper = 10;
            elseif value <= 74
                helper = 11;
            elseif value <= 79
                helper = 12;
            else
                helper = 13;
            end 
            app.current_feature(1,19) = helper;
        end

        % Value changed function: BMIroundedtointegerEditField
        function BMIroundedtointegerEditFieldValueChanged(app, event)
            value = app.BMIroundedtointegerEditField.Value;
            app.current_feature(1, 4) = value; % pass on the BMI
        end

        % Value changed function: 
        % Dayswithbadmentalhealthinthelast30daysEditField
        function Dayswithbadmentalhealthinthelast30daysEditFieldValueChanged(app, event)
            value = app.Dayswithbadmentalhealthinthelast30daysEditField.Value;
            app.current_feature(1, 15) = value; % pass on the nmbr of days
        end

        % Value changed function: 
        % Dayswithbadphysicalhealthinthelast30daysEditField
        function Dayswithbadphysicalhealthinthelast30daysEditFieldValueChanged(app, event)
            value = app.Dayswithbadphysicalhealthinthelast30daysEditField.Value;
            app.current_feature(1, 16) = value; % pass on the nmbr of days
        end

        % Value changed function: AnnualhouseholdincomeallsourcesEditField
        function AnnualhouseholdincomeallsourcesEditFieldValueChanged(app, event)
            value = app.AnnualhouseholdincomeallsourcesEditField.Value;
            if value < 10000
                helper = 1;
            elseif value < 15000
                helper = 2;
            elseif value < 20000
                helper = 3;
            elseif value < 25000
                helper = 4;
            elseif value < 35000
                helper = 5;
            elseif value < 50000
                helper = 6;
            elseif value < 75000
                helper = 7;
            elseif value >= 75000
                helper = 8;
            end 
            app.current_feature(1,21) = helper;
        end

        % Value changed function: HeavyalcoholconsumptionCheckBox
        function HeavyalcoholconsumptionCheckBoxValueChanged(app, event)
            value = app.HeavyalcoholconsumptionCheckBox.Value;
            app.current_feature(1, 11) = value; 
        end

        % Value changed function: 
        % Smokedatleast100cigarettesinentirelifeCheckBox
        function Smokedatleast100cigarettesinentirelifeCheckBoxValueChanged(app, event)
            value = app.Smokedatleast100cigarettesinentirelifeCheckBox.Value;
            app.current_feature(1, 5) = value;
        end

        % Value changed function: 
        % SeriousdifficultywalkingorclimbingstairsCheckBox
        function SeriousdifficultywalkingorclimbingstairsCheckBoxValueChanged(app, event)
            value = app.SeriousdifficultywalkingorclimbingstairsCheckBox.Value;
            app.current_feature(1, 17) = value;
        end

        % Value changed function: Hasexercisedinthelast30daysCheckBox
        function Hasexercisedinthelast30daysCheckBoxValueChanged(app, event)
            value = app.Hasexercisedinthelast30daysCheckBox.Value;
            app.current_feature(1, 8) = value;
        end

        % Value changed function: EatsatleastonefruitadayCheckBox
        function EatsatleastonefruitadayCheckBoxValueChanged(app, event)
            value = app.EatsatleastonefruitadayCheckBox.Value;
            app.current_feature(1, 9) = value;
        end

        % Value changed function: EatsatleastonevegetableadayCheckBox
        function EatsatleastonevegetableadayCheckBoxValueChanged(app, event)
            value = app.EatsatleastonevegetableadayCheckBox.Value;
            app.current_feature(1, 10) = value;
        end

        % Value changed function: EverhadhighbloodcholersterolCheckBox
        function EverhadhighbloodcholersterolCheckBoxValueChanged(app, event)
            value = app.EverhadhighbloodcholersterolCheckBox.Value;
            app.current_feature(1, 2) = value;
        end

        % Value changed function: Highbloodcholesterolinlast5yearsCheckBox
        function Highbloodcholesterolinlast5yearsCheckBoxValueChanged(app, event)
            value = app.Highbloodcholesterolinlast5yearsCheckBox.Value;
            app.current_feature(1, 3) = value;
        end

        % Value changed function: HadastrokeCheckBox
        function HadastrokeCheckBoxValueChanged(app, event)
            value = app.HadastrokeCheckBox.Value;
            app.current_feature(1, 6) = value;
        end

        % Value changed function: HighbloodpressureCheckBox
        function HighbloodpressureCheckBoxValueChanged(app, event)
            value = app.HighbloodpressureCheckBox.Value;
            app.current_feature(1, 1) = value;
        end

        % Value changed function: HasanykindofhealthcarecoverageCheckBox
        function HasanykindofhealthcarecoverageCheckBoxValueChanged(app, event)
            value = app.HasanykindofhealthcarecoverageCheckBox.Value;
            app.current_feature(1, 12) = value;
        end

        % Value changed function: 
        % Coststoohighfordoctorssvisitinlast12monthsCheckBox
        function Coststoohighfordoctorssvisitinlast12monthsCheckBoxValueChanged(app, event)
            value = app.Coststoohighfordoctorssvisitinlast12monthsCheckBox.Value;
            app.current_feature(1, 13) = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.GeneralDiseasesButton = uibutton(app.HomeTab, 'push');
            app.GeneralDiseasesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralDiseasesButtonPushed, true);
            app.GeneralDiseasesButton.Text = 'GeneralDiseases';
            app.DiabetesButton = uibutton(app.HomeTab, 'push');
            app.DiabetesButton.ButtonPushedFcn = createCallbackFcn(app, @DiabetesButtonPushed, true);
            app.DiabetesButton.Text = 'Diabetes';
            app.HeartcomplicationsButton = uibutton(app.HomeTab, 'push');
            app.HeartcomplicationsButton.ButtonPushedFcn = createCallbackFcn(app, @HeartcomplicationsButtonPushed, true);
            app.HeartcomplicationsButton.Text = 'Heartcomplications';
            app.LumpyskinButton = uibutton(app.HomeTab, 'push');
            app.LumpyskinButton.ButtonPushedFcn = createCallbackFcn(app, @LumpyskinButtonPushed, true);
            app.LumpyskinButton.Text = 'Lumpyskin';
            app.PleaseSelectwhatyouwanttoexamineyourpatientforLabel = uilabel(app.HomeTab);
            app.PleaseSelectwhatyouwanttoexamineyourpatientforLabel.Text = 'Please Select what you want to examine your patient for:';
            app.GeneralDiseasesTab_2 = uitab(app.TabGroup);
            app.GeneralDiseasesTab_2.Title = 'GeneralDiseases';
            app.DiabetesTab = uitab(app.TabGroup);
            app.DiabetesTab.Title = 'Diabetes';
            app.HeartcomplicationsTab = uitab(app.TabGroup);
            app.HeartcomplicationsTab.Title = 'Heartcomplications';
            app.TextArea = uitextarea(app.HeartcomplicationsTab);
            app.TextArea.Value = {'Do you have exact measurements or do you just want to test for heart diseases in general?'};
            app.ExactButton = uibutton(app.HeartcomplicationsTab, 'push');
            app.ExactButton.ButtonPushedFcn = createCallbackFcn(app, @ExactButtonPushed, true);
            app.ExactButton.Text = 'Exact';
            app.GeneralButton = uibutton(app.HeartcomplicationsTab, 'push');
            app.GeneralButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralButtonPushed, true);
            app.GeneralButton.Text = 'General';
            app.ExactMeasurementsTab = uitab(app.TabGroup);
            app.ExactMeasurementsTab.Title = 'ExactMeasurements';
            app.GeneralMeasurementsTab = uitab(app.TabGroup);
            app.GeneralMeasurementsTab.Title = 'GeneralMeasurements';
            app.HeavyalcoholconsumptionCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.HeavyalcoholconsumptionCheckBox.ValueChangedFcn = createCallbackFcn(app, @HeavyalcoholconsumptionCheckBoxValueChanged, true);
            app.HeavyalcoholconsumptionCheckBox.Text = 'Heavy alcohol consumption';
            app.Smokedatleast100cigarettesinentirelifeCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.Smokedatleast100cigarettesinentirelifeCheckBox.ValueChangedFcn = createCallbackFcn(app, @Smokedatleast100cigarettesinentirelifeCheckBoxValueChanged, true);
            app.Smokedatleast100cigarettesinentirelifeCheckBox.Text = 'Smoked at least 100 cigarettes in entire life';
            app.Highbloodcholesterolinlast5yearsCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.Highbloodcholesterolinlast5yearsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Highbloodcholesterolinlast5yearsCheckBoxValueChanged, true);
            app.Highbloodcholesterolinlast5yearsCheckBox.Text = 'High blood cholesterol in last 5 years';
            app.HighbloodpressureCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.HighbloodpressureCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighbloodpressureCheckBoxValueChanged, true);
            app.HighbloodpressureCheckBox.Text = 'High blood pressure';
            app.HadastrokeCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.HadastrokeCheckBox.ValueChangedFcn = createCallbackFcn(app, @HadastrokeCheckBoxValueChanged, true);
            app.HadastrokeCheckBox.Text = 'Had a stroke';
            app.Hasexercisedinthelast30daysCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.Hasexercisedinthelast30daysCheckBox.ValueChangedFcn = createCallbackFcn(app, @Hasexercisedinthelast30daysCheckBoxValueChanged, true);
            app.Hasexercisedinthelast30daysCheckBox.Text = 'Has exercised in the last 30 days';
            app.EverhadhighbloodcholersterolCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.EverhadhighbloodcholersterolCheckBox.ValueChangedFcn = createCallbackFcn(app, @EverhadhighbloodcholersterolCheckBoxValueChanged, true);
            app.EverhadhighbloodcholersterolCheckBox.Text = 'Ever had high blood cholersterol';
            app.EatsatleastonevegetableadayCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.EatsatleastonevegetableadayCheckBox.ValueChangedFcn = createCallbackFcn(app, @EatsatleastonevegetableadayCheckBoxValueChanged, true);
            app.EatsatleastonevegetableadayCheckBox.Text = 'Eats at least one vegetable a day';
            app.EatsatleastonefruitadayCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.EatsatleastonefruitadayCheckBox.ValueChangedFcn = createCallbackFcn(app, @EatsatleastonefruitadayCheckBoxValueChanged, true);
            app.EatsatleastonefruitadayCheckBox.Text = 'Eats at least one fruit a day';
            app.SeriousdifficultywalkingorclimbingstairsCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.SeriousdifficultywalkingorclimbingstairsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeriousdifficultywalkingorclimbingstairsCheckBoxValueChanged, true);
            app.SeriousdifficultywalkingorclimbingstairsCheckBox.Text = 'Serious difficulty walking or climbing stairs';
            app.FeedbackTextArea = uitextarea(app.GeneralMeasurementsTab);
            app.DIAGNOSISButton = uibutton(app.GeneralMeasurementsTab, 'push');
            app.DIAGNOSISButton.ButtonPushedFcn = createCallbackFcn(app, @DIAGNOSISButtonPushed, true);
            app.DIAGNOSISButton.Text = 'DIAGNOSIS';
            app.EditField2 = uieditfield(app.GeneralMeasurementsTab, 'text');
            app.EditField2.Value = '       For more information, click here >>> ';
            app.PleaseenterthepatientsvaluesbellowLabel = uilabel(app.GeneralMeasurementsTab);
            app.PleaseenterthepatientsvaluesbellowLabel.Text = '► Please enter the patients values bellow:';
            app.BMIroundedtointegerEditFieldLabel = uilabel(app.GeneralMeasurementsTab);
            app.BMIroundedtointegerEditFieldLabel.Text = 'BMI rounded to integer';
            app.BMIroundedtointegerEditField = uieditfield(app.GeneralMeasurementsTab, 'numeric');
            app.BMIroundedtointegerEditField.ValueChangedFcn = createCallbackFcn(app, @BMIroundedtointegerEditFieldValueChanged, true);
            app.AgeEditFieldLabel = uilabel(app.GeneralMeasurementsTab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.GeneralMeasurementsTab, 'numeric');
            app.AgeEditField.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.DiabetesDropDownLabel = uilabel(app.GeneralMeasurementsTab);
            app.DiabetesDropDownLabel.Text = 'Diabetes';
            app.DiabetesDropDown = uidropdown(app.GeneralMeasurementsTab);
            app.DiabetesDropDown.Items = {'Yes (outside of pregnancy)', 'No', 'Borderline'};
            app.DiabetesDropDown.ValueChangedFcn = createCallbackFcn(app, @DiabetesDropDownValueChanged, true);
            app.DiabetesDropDown.Value = 'Yes (outside of pregnancy)';
            app.GeneralHealthDropDownLabel = uilabel(app.GeneralMeasurementsTab);
            app.GeneralHealthDropDownLabel.Text = 'General Health';
            app.GeneralHealthDropDown = uidropdown(app.GeneralMeasurementsTab);
            app.GeneralHealthDropDown.Items = {'Excellent', 'Very good', 'Good', 'Fair', 'Poor'};
            app.GeneralHealthDropDown.ValueChangedFcn = createCallbackFcn(app, @GeneralHealthDropDownValueChanged, true);
            app.GeneralHealthDropDown.Value = 'Excellent';
            app.HighestcompletedGradeDropDownLabel = uilabel(app.GeneralMeasurementsTab);
            app.HighestcompletedGradeDropDownLabel.Text = 'Highest completed Grade';
            app.HighestcompletedGradeDropDown = uidropdown(app.GeneralMeasurementsTab);
            app.HighestcompletedGradeDropDown.Items = {'No school or only kindergarten', 'Elementary', 'Grades 9 through 11', 'Grade 12 or GED', 'College 1-3 years', 'College for at least 4 years'};
            app.HighestcompletedGradeDropDown.ValueChangedFcn = createCallbackFcn(app, @HighestcompletedGradeDropDownValueChanged, true);
            app.HighestcompletedGradeDropDown.Value = 'No school or only kindergarten';
            app.AnnualhouseholdincomeallsourcesLabel = uilabel(app.GeneralMeasurementsTab);
            app.AnnualhouseholdincomeallsourcesLabel.Text = 'Annual household income (all sources)';
            app.AnnualhouseholdincomeallsourcesEditField = uieditfield(app.GeneralMeasurementsTab, 'numeric');
            app.AnnualhouseholdincomeallsourcesEditField.ValueChangedFcn = createCallbackFcn(app, @AnnualhouseholdincomeallsourcesEditFieldValueChanged, true);
            app.SexDropDownLabel = uilabel(app.GeneralMeasurementsTab);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.GeneralMeasurementsTab);
            app.SexDropDown.Items = {'Male', 'Female'};
            app.SexDropDown.ValueChangedFcn = createCallbackFcn(app, @SexDropDownValueChanged, true);
            app.SexDropDown.Value = 'Male';
            app.Dayswithbadmentalhealthinthelast30daysEditFieldLabel = uilabel(app.GeneralMeasurementsTab);
            app.Dayswithbadmentalhealthinthelast30daysEditFieldLabel.Text = 'Days with bad mental health in the last 30 days';
            app.Dayswithbadmentalhealthinthelast30daysEditField = uieditfield(app.GeneralMeasurementsTab, 'numeric');
            app.Dayswithbadmentalhealthinthelast30daysEditField.ValueChangedFcn = createCallbackFcn(app, @Dayswithbadmentalhealthinthelast30daysEditFieldValueChanged, true);
            app.Dayswithbadphysicalhealthinthelast30daysEditFieldLabel = uilabel(app.GeneralMeasurementsTab);
            app.Dayswithbadphysicalhealthinthelast30daysEditFieldLabel.Text = 'Days with bad physical health in the last 30 days';
            app.Dayswithbadphysicalhealthinthelast30daysEditField = uieditfield(app.GeneralMeasurementsTab, 'numeric');
            app.Dayswithbadphysicalhealthinthelast30daysEditField.ValueChangedFcn = createCallbackFcn(app, @Dayswithbadphysicalhealthinthelast30daysEditFieldValueChanged, true);
            app.Coststoohighfordoctorssvisitinlast12monthsCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.Coststoohighfordoctorssvisitinlast12monthsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Coststoohighfordoctorssvisitinlast12monthsCheckBoxValueChanged, true);
            app.Coststoohighfordoctorssvisitinlast12monthsCheckBox.Text = 'Costs too high for doctors''s visit (in last 12 months)';
            app.HasanykindofhealthcarecoverageCheckBox = uicheckbox(app.GeneralMeasurementsTab);
            app.HasanykindofhealthcarecoverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @HasanykindofhealthcarecoverageCheckBoxValueChanged, true);
            app.HasanykindofhealthcarecoverageCheckBox.Text = 'Has any kind of healthcare coverage';
            app.LumpyskinTab = uitab(app.TabGroup);
            app.LumpyskinTab.Title = 'Lumpyskin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end