classdef LSapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Prediction                   matlab.ui.control.Label
        PressbuttonforthepredictionLabel  matlab.ui.control.Label
        Label_13                     matlab.ui.control.Label
        MoreInformationLabel         matlab.ui.control.Label
        Label_11                     matlab.ui.control.Label
        Label_10                     matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Image5                       matlab.ui.control.Image
        InfoButton                   matlab.ui.control.StateButton
        Hyperlink                    matlab.ui.control.Hyperlink
        Label                        matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        PleaseenterthevaluesbellowLabel  matlab.ui.control.Label
        LumpyskindiseaseTextArea     matlab.ui.control.TextArea
        diagnosisButton              matlab.ui.control.Button
        tmxEditField_2               matlab.ui.control.NumericEditField
        tmxEditField_2Label          matlab.ui.control.Label
        tmpEditField_2               matlab.ui.control.NumericEditField
        tmpEditField_2Label          matlab.ui.control.Label
        tmnEditField_2               matlab.ui.control.NumericEditField
        tmnEditField_2Label          matlab.ui.control.Label
        preEditField_2               matlab.ui.control.NumericEditField
        preEditField_2Label          matlab.ui.control.Label
        X5_Bf_2010_DaEditField       matlab.ui.control.NumericEditField
        X5_Bf_2010_DaEditFieldLabel  matlab.ui.control.Label
        X5_Ct_2010_DaEditField       matlab.ui.control.NumericEditField
        X5_Ct_2010_DaEditFieldLabel  matlab.ui.control.Label
        dominantlandEditField        matlab.ui.control.NumericEditField
        dominantlandEditFieldLabel   matlab.ui.control.Label
        elevationEditField           matlab.ui.control.NumericEditField
        elevationEditFieldLabel      matlab.ui.control.Label
        wetEditField                 matlab.ui.control.NumericEditField
        wetEditFieldLabel            matlab.ui.control.Label
        vrapEditField                matlab.ui.control.NumericEditField
        vrapEditFieldLabel           matlab.ui.control.Label
        petEditField                 matlab.ui.control.NumericEditField
        petEditFieldLabel            matlab.ui.control.Label
        frsEditField                 matlab.ui.control.NumericEditField
        frsEditFieldLabel            matlab.ui.control.Label
        dtrEditField                 matlab.ui.control.NumericEditField
        dtrEditFieldLabel            matlab.ui.control.Label
        cldEditField                 matlab.ui.control.NumericEditField
        cldEditFieldLabel            matlab.ui.control.Label
        ycoordinateEditField         matlab.ui.control.NumericEditField
        ycoordinateEditFieldLabel    matlab.ui.control.Label
        xcoordinateEditField         matlab.ui.control.NumericEditField
        xcoordinateEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        input % array to store the input values
        input_index = 1; % index for array
        Model; % ML model for lumpy skin
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("LS_MLmodel.mat", "MLmodel_ls"); 
            
            app.input = zeros(1,16);
            app.Model = MLmodel_ls;

        end

        % Value changed function: xcoordinateEditField
        function xcoordinateEditFieldValueChanged(app, event)
            x_value = app.xcoordinateEditField.Value;
            app.input(1, app.input_index) = x_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: ycoordinateEditField
        function ycoordinateEditFieldValueChanged(app, event)
            y_value = app.ycoordinateEditField.Value;
            app.input(1, app.input_index) = y_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: cldEditField
        function cldEditFieldValueChanged(app, event)
            cld_value = app.cldEditField.Value;
            app.input(1, app.input_index) = cld_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: dtrEditField
        function dtrEditFieldValueChanged(app, event)
            dtr_value = app.dtrEditField.Value;
            app.input(1, app.input_index) = dtr_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: frsEditField
        function frsEditFieldValueChanged(app, event)
            frs_value = app.frsEditField.Value;
            app.input(1, app.input_index) = frs_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: petEditField
        function petEditFieldValueChanged(app, event)
            pet_value = app.petEditField.Value;
            app.input(1, app.input_index) = pet_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: preEditField_2
        function preEditField_2ValueChanged(app, event)
            pre_value = app.preEditField_2.Value;
            app.input(1, app.input_index) = pre_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: tmnEditField_2
        function tmnEditField_2ValueChanged(app, event)
            tmn_value = app.tmnEditField_2.Value;
            app.input(1, app.input_index) = tmn_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: tmpEditField_2
        function tmpEditField_2ValueChanged(app, event)
            tmp_value = app.tmpEditField_2.Value;
            app.input(1, app.input_index) = tmp_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: tmxEditField_2
        function tmxEditField_2ValueChanged(app, event)
            tmx_value = app.tmxEditField_2.Value;
            app.input(1, app.input_index) = tmx_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: vrapEditField
        function vrapEditFieldValueChanged(app, event)
            vrap_value = app.vrapEditField.Value;
            app.input(1, app.input_index) = vrap_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: wetEditField
        function wetEditFieldValueChanged(app, event)
            wet_value = app.wetEditField.Value;
            app.input(1, app.input_index) = wet_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: elevationEditField
        function elevationEditFieldValueChanged(app, event)
            ele_value = app.elevationEditField.Value;
            app.input(1, app.input_index) = ele_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: dominantlandEditField
        function dominantlandEditFieldValueChanged(app, event)
            dlc_value = app.dominantlandEditField.Value;
            app.input(1, app.input_index) = dlc_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: X5_Ct_2010_DaEditField
        function X5_Ct_2010_DaEditFieldValueChanged(app, event)
            X5_Ct_value = app.X5_Ct_2010_DaEditField.Value;
            app.input(1, app.input_index) = X5_Ct_value;
            app.input_index = app.input_index +1;
        end

        % Value changed function: X5_Bf_2010_DaEditField
        function X5_Bf_2010_DaEditFieldValueChanged(app, event)
            X5_Bf_value = app.X5_Bf_2010_DaEditField.Value;
            app.input(1, app.input_index) = X5_Bf_value;
            app.input_index = app.input_index +1;
        end

        % Button pushed function: diagnosisButton
        function diagnosisButtonPushed(app, event)
            result = predict(app.Model, app.input);
            
            if strcmp(result, 1)
                app.Prediction.Text = "According to the Diagnosis, your cow probably has Lumpy Skin.";
            else
                if all(app.input == 0)
                    app.Prediction.Text = "You have not entered any values, therefore no disease is detected. Please enter some values.";
                else
                    app.Prediction.Text = "According to the Diagnosis, your cow probably does not have Lumpy Skin.";
                end
            end 
        end

        % Value changed function: InfoButton
        function InfoButtonValueChanged(app, event)
            app.MoreInformationLabel.Visible = "off";
            value = app.InfoButton.Value;
            if value 
                app.LumpyskindiseaseTextArea.Visible = "on";
            else
                app.LumpyskindiseaseTextArea.Visible = "off";
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xcoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.xcoordinateEditFieldLabel.Text = 'x-coordinate';
            app.xcoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.xcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.ycoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.ycoordinateEditFieldLabel.Text = 'y-coordinate';
            app.ycoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.ycoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @ycoordinateEditFieldValueChanged, true);
            app.cldEditFieldLabel = uilabel(app.UIFigure);
            app.cldEditFieldLabel.Text = 'cld';
            app.cldEditField = uieditfield(app.UIFigure, 'numeric');
            app.cldEditField.ValueChangedFcn = createCallbackFcn(app, @cldEditFieldValueChanged, true);
            app.dtrEditFieldLabel = uilabel(app.UIFigure);
            app.dtrEditFieldLabel.Text = 'dtr';
            app.dtrEditField = uieditfield(app.UIFigure, 'numeric');
            app.dtrEditField.ValueChangedFcn = createCallbackFcn(app, @dtrEditFieldValueChanged, true);
            app.frsEditFieldLabel = uilabel(app.UIFigure);
            app.frsEditFieldLabel.Text = 'frs';
            app.frsEditField = uieditfield(app.UIFigure, 'numeric');
            app.frsEditField.ValueChangedFcn = createCallbackFcn(app, @frsEditFieldValueChanged, true);
            app.petEditFieldLabel = uilabel(app.UIFigure);
            app.petEditFieldLabel.Text = 'pet';
            app.petEditField = uieditfield(app.UIFigure, 'numeric');
            app.petEditField.ValueChangedFcn = createCallbackFcn(app, @petEditFieldValueChanged, true);
            app.vrapEditFieldLabel = uilabel(app.UIFigure);
            app.vrapEditFieldLabel.Text = 'vrap';
            app.vrapEditField = uieditfield(app.UIFigure, 'numeric');
            app.vrapEditField.ValueChangedFcn = createCallbackFcn(app, @vrapEditFieldValueChanged, true);
            app.wetEditFieldLabel = uilabel(app.UIFigure);
            app.wetEditFieldLabel.Text = 'wet';
            app.wetEditField = uieditfield(app.UIFigure, 'numeric');
            app.wetEditField.ValueChangedFcn = createCallbackFcn(app, @wetEditFieldValueChanged, true);
            app.elevationEditFieldLabel = uilabel(app.UIFigure);
            app.elevationEditFieldLabel.Text = 'elevation';
            app.elevationEditField = uieditfield(app.UIFigure, 'numeric');
            app.elevationEditField.ValueChangedFcn = createCallbackFcn(app, @elevationEditFieldValueChanged, true);
            app.dominantlandEditFieldLabel = uilabel(app.UIFigure);
            app.dominantlandEditFieldLabel.Text = 'dominant land';
            app.dominantlandEditField = uieditfield(app.UIFigure, 'numeric');
            app.dominantlandEditField.ValueChangedFcn = createCallbackFcn(app, @dominantlandEditFieldValueChanged, true);
            app.X5_Ct_2010_DaEditFieldLabel = uilabel(app.UIFigure);
            app.X5_Ct_2010_DaEditFieldLabel.Text = 'X5_Ct_2010_Da';
            app.X5_Ct_2010_DaEditField = uieditfield(app.UIFigure, 'numeric');
            app.X5_Ct_2010_DaEditField.ValueChangedFcn = createCallbackFcn(app, @X5_Ct_2010_DaEditFieldValueChanged, true);
            app.X5_Bf_2010_DaEditFieldLabel = uilabel(app.UIFigure);
            app.X5_Bf_2010_DaEditFieldLabel.Text = 'X5_Bf_2010_Da';
            app.X5_Bf_2010_DaEditField = uieditfield(app.UIFigure, 'numeric');
            app.X5_Bf_2010_DaEditField.ValueChangedFcn = createCallbackFcn(app, @X5_Bf_2010_DaEditFieldValueChanged, true);
            app.preEditField_2Label = uilabel(app.UIFigure);
            app.preEditField_2Label.Text = 'pre';
            app.preEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.preEditField_2.ValueChangedFcn = createCallbackFcn(app, @preEditField_2ValueChanged, true);
            app.tmnEditField_2Label = uilabel(app.UIFigure);
            app.tmnEditField_2Label.Text = 'tmn';
            app.tmnEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.tmnEditField_2.ValueChangedFcn = createCallbackFcn(app, @tmnEditField_2ValueChanged, true);
            app.tmpEditField_2Label = uilabel(app.UIFigure);
            app.tmpEditField_2Label.Text = 'tmp';
            app.tmpEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.tmpEditField_2.ValueChangedFcn = createCallbackFcn(app, @tmpEditField_2ValueChanged, true);
            app.tmxEditField_2Label = uilabel(app.UIFigure);
            app.tmxEditField_2Label.Text = 'tmx';
            app.tmxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.tmxEditField_2.ValueChangedFcn = createCallbackFcn(app, @tmxEditField_2ValueChanged, true);
            app.diagnosisButton = uibutton(app.UIFigure, 'push');
            app.diagnosisButton.ButtonPushedFcn = createCallbackFcn(app, @diagnosisButtonPushed, true);
            app.diagnosisButton.Text = 'diagnosis';
            app.LumpyskindiseaseTextArea = uitextarea(app.UIFigure);
            app.LumpyskindiseaseTextArea.Visible = 'off';
            app.LumpyskindiseaseTextArea.Value = {'"Lumpy skin disease (LSD) is a highly contagious viral disease of cattle and buffalo that causes relatively low mortality. It does not affect humans. The disease can result in animal welfare issues and significant production losses."'};
            app.PleaseenterthevaluesbellowLabel = uilabel(app.UIFigure);
            app.PleaseenterthevaluesbellowLabel.Text = '► Please enter the values bellow:';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '🔍  For further information click here  >>>  ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Tooltip = {'cloud cover'};
            app.Label.Text = '';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://www.agriculture.gov.au/biosecurity-trade/pests-diseases-weeds/animal/lumpy-skin-disease#:~:text=Lumpy%20skin%20disease%20(LSD)%20is,issues%20and%20significant%20production%20losses.';
            app.Hyperlink.Text = 'Lumpy skin disease';
            app.InfoButton = uibutton(app.UIFigure, 'state');
            app.InfoButton.ValueChangedFcn = createCallbackFcn(app, @InfoButtonValueChanged, true);
            app.InfoButton.Icon = fullfile(pathToMLAPP, 'Teamprojekt Infobutton.png');
            app.InfoButton.Text = '';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'world_map_mariella_teamproject.png');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Tooltip = {'diurnal temperature range'};
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Tooltip = {'frost day frequence'};
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Tooltip = {'Potential Evapo-transpiration'};
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Tooltip = {'precipitation'};
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Tooltip = {'monthly average daily minimum temperature'};
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Tooltip = {'daily mean temperature'};
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Tooltip = {'monthly average daily maximum temperature'};
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Tooltip = {'vapour pressure'};
            app.Label_9.Text = '';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Tooltip = {'wet day frequence'};
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Tooltip = {'dominant land cover'};
            app.Label_11.Text = '';
            app.MoreInformationLabel = uilabel(app.UIFigure);
            app.MoreInformationLabel.Text = 'For more information press the button!';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Tooltip = {'https://onlinelibrary.wiley.com'};
            app.Label_13.Text = '';
            app.PressbuttonforthepredictionLabel = uilabel(app.UIFigure);
            app.PressbuttonforthepredictionLabel.Text = 'Press button for the prediction:';
            app.Prediction = uilabel(app.UIFigure);
            app.Prediction.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LSapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end